classdef CC_app_GUI_201126 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        file                            matlab.ui.container.Menu
        save                            matlab.ui.container.Menu
        edit                            matlab.ui.container.Menu
        new_model                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        FutureCOTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        COconcentrationsTab             matlab.ui.container.Tab
        axes2                           matlab.ui.control.UIAxes
        rcp_scenarios_2                 matlab.ui.container.ButtonGroup
        radiobutton6_2                  matlab.ui.control.RadioButton
        radiobutton7_2                  matlab.ui.control.RadioButton
        radiobutton8_2                  matlab.ui.control.RadioButton
        radiobutton9_2                  matlab.ui.control.RadioButton
        radiobutton10_2                 matlab.ui.control.RadioButton
        TextArea_12                     matlab.ui.control.TextArea
        COemissionsTab                  matlab.ui.container.Tab
        axes3                           matlab.ui.control.UIAxes
        rcp_scenarios_3                 matlab.ui.container.ButtonGroup
        radiobutton6_3                  matlab.ui.control.RadioButton
        radiobutton7_3                  matlab.ui.control.RadioButton
        radiobutton8_3                  matlab.ui.control.RadioButton
        radiobutton9_3                  matlab.ui.control.RadioButton
        radiobutton10_3                 matlab.ui.control.RadioButton
        TextArea_2                      matlab.ui.control.TextArea
        CObudgetTab                     matlab.ui.container.Tab
        CountryDropDownLabel            matlab.ui.control.Label
        CountryDropDown                 matlab.ui.control.DropDown
        CO2budget_Axes                  matlab.ui.control.UIAxes
        BudgetGtonCO2Label              matlab.ui.control.Label
        BudgetGtonCO2TextArea           matlab.ui.control.TextArea
        Yearsto0emissionsfrom2018Label  matlab.ui.control.Label
        Yearstoemissions                matlab.ui.control.TextArea
        ShowbudgetButton                matlab.ui.control.Button
        TextArea_16                     matlab.ui.control.TextArea
        keepthecurrentplots             matlab.ui.control.CheckBox
        GlobalbudgetGtonCO2Label        matlab.ui.control.Label
        Globalbudget                    matlab.ui.control.NumericEditField
        COcalculatorTab                 matlab.ui.container.Tab
        TextArea_36                     matlab.ui.control.TextArea
        COfootprinttonsperyearEditFieldLabel  matlab.ui.control.Label
        COfootprintEditField            matlab.ui.control.NumericEditField
        LengthmEditFieldLabel           matlab.ui.control.Label
        LengthmEditField                matlab.ui.control.NumericEditField
        WidthmEditFieldLabel            matlab.ui.control.Label
        WidthmEditField                 matlab.ui.control.NumericEditField
        DepthmEditFieldLabel            matlab.ui.control.Label
        DepthmEditField                 matlab.ui.control.NumericEditField
        TextArea_38                     matlab.ui.control.TextArea
        NumberofolympicswimmingpoolsfilledwithCOLabel  matlab.ui.control.Label
        swimmingpoolsTextArea           matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        SwimmingpoolsizeLabel           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        PressurehPaSliderLabel          matlab.ui.control.Label
        PressurehPaSlider               matlab.ui.control.Slider
        TemperatureCSliderLabel         matlab.ui.control.Label
        TemperatureCSlider              matlab.ui.control.Slider
        ChangeheightandpressureasyoulikethevolumedependsonbothLabel  matlab.ui.control.Label
        HistoricaldataTab               matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        ENSOTab                         matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Elnino_Axes                     matlab.ui.control.UIAxes
        TextArea_29                     matlab.ui.control.TextArea
        AMOTab_2                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        AMO_Axes                        matlab.ui.control.UIAxes
        TextArea_30                     matlab.ui.control.TextArea
        IPOTab                          matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        IPO_Axes                        matlab.ui.control.UIAxes
        TextArea_31                     matlab.ui.control.TextArea
        PDOTab                          matlab.ui.container.Tab
        GridLayout2_3                   matlab.ui.container.GridLayout
        PDO_Axes                        matlab.ui.control.UIAxes
        TextArea_32                     matlab.ui.control.TextArea
        SunspotnumbersTab               matlab.ui.container.Tab
        GridLayout2_4                   matlab.ui.container.GridLayout
        sunspots_Axes                   matlab.ui.control.UIAxes
        TextArea_33                     matlab.ui.control.TextArea
        VolcanicactivityTab             matlab.ui.container.Tab
        GridLayout2_5                   matlab.ui.container.GridLayout
        volcanic_Axes                   matlab.ui.control.UIAxes
        TextArea_34                     matlab.ui.control.TextArea
        VEI_table                       matlab.ui.control.Table
        TextArea_35                     matlab.ui.control.TextArea
        MakeplotsTab                    matlab.ui.container.Tab
        compare_Axes                    matlab.ui.control.UIAxes
        PlotvariablesButton             matlab.ui.control.Button
        PlotvariablesagainsttimeLabel   matlab.ui.control.Label
        When2variablesareselectedyoucanplotthemagainsteachotherLabel  matlab.ui.control.Label
        MakescatterplotButton           matlab.ui.control.Button
        VariablestoplotPanel            matlab.ui.container.Panel
        ENSOCheckBox                    matlab.ui.control.CheckBox
        AMOCheckBox                     matlab.ui.control.CheckBox
        IPOCheckBox                     matlab.ui.control.CheckBox
        PDOCheckBox                     matlab.ui.control.CheckBox
        SunspotnumbersCheckBox          matlab.ui.control.CheckBox
        VolcanicactivityCheckBox        matlab.ui.control.CheckBox
        TemperatureanomaliesCheckBox    matlab.ui.control.CheckBox
        NormalizeCheckBox               matlab.ui.control.CheckBox
        Studyperiodsofdifferentindizesworksonlyfor1variableatatimeLabel  matlab.ui.control.Label
        PlotfrequenciesButton           matlab.ui.control.Button
        StudyhowperiodschangeovertimeLabel  matlab.ui.control.Label
        MakewaveletplotButton           matlab.ui.control.Button
        StatisticalmodelTab             matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        TrainingdataTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        TemperatureTab                  matlab.ui.container.Tab
        axes4                           matlab.ui.control.UIAxes
        AMOTab                          matlab.ui.container.Tab
        axes4_2                         matlab.ui.control.UIAxes
        SunspotnumberTab                matlab.ui.container.Tab
        axes4_3                         matlab.ui.control.UIAxes
        COTab                           matlab.ui.container.Tab
        axes4_4                         matlab.ui.control.UIAxes
        ModelevaluationTab              matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        HistoricalDataTab               matlab.ui.container.Tab
        Scatterplot_Button              matlab.ui.control.Button
        residuals                       matlab.ui.control.UIAxes
        residuals_Button                matlab.ui.control.Button
        scatterplot                     matlab.ui.control.UIAxes
        TextArea_20                     matlab.ui.control.TextArea
        ErrorestimatesLabel             matlab.ui.control.Label
        IPCCmodelsTab                   matlab.ui.container.Tab
        cmip5                           matlab.ui.control.UIAxes
        rcp_scenarios_4                 matlab.ui.container.ButtonGroup
        radiobutton7_6                  matlab.ui.control.RadioButton
        radiobutton8_6                  matlab.ui.control.RadioButton
        radiobutton9_6                  matlab.ui.control.RadioButton
        TextArea_21                     matlab.ui.control.TextArea
        TextArea_22                     matlab.ui.control.TextArea
        ModifyscenariosTab_2            matlab.ui.container.Tab
        TextArea_17                     matlab.ui.control.TextArea
        RunscenariosPanel               matlab.ui.container.Panel
        RuncustommodelButton            matlab.ui.control.Button
        ComparewithRCPscenariosButton   matlab.ui.control.Button
        PreparescenariosPanel           matlab.ui.container.Panel
        ImportRCPButton                 matlab.ui.control.Button
        ImportAMOButton                 matlab.ui.control.Button
        ImportsunspotnumbersButton      matlab.ui.control.Button
        PlotRCPButton                   matlab.ui.control.Button
        PlotAMOButton                   matlab.ui.control.Button
        PlotsunspotnumbersButton        matlab.ui.control.Button
        TextArea_18                     matlab.ui.control.TextArea
        modifiedmodel                   matlab.ui.control.UIAxes
        ModifymodelTab_2                matlab.ui.container.Tab
        TextArea_23                     matlab.ui.control.TextArea
        InputdataPanel                  matlab.ui.container.Panel
        ImportinputdataButton           matlab.ui.control.Button
        TextArea_25                     matlab.ui.control.TextArea
        modmodel                        matlab.ui.control.UIAxes
        TrainmodelPanel                 matlab.ui.container.Panel
        TrainmodelButton                matlab.ui.control.Button
        TextArea_26                     matlab.ui.control.TextArea
        Lamp                            matlab.ui.control.Lamp
        TestmodelPanel                  matlab.ui.container.Panel
        TextArea_27                     matlab.ui.control.TextArea
        TestmodelButton                 matlab.ui.control.Button
        TextArea_24                     matlab.ui.control.TextArea
        modmodel_2                      matlab.ui.control.UIAxes
        InformationTab_2                matlab.ui.container.Tab
        info_text                       matlab.ui.control.Label
        ExportInputdataButton           matlab.ui.control.Button
        ExportRCPdataButton             matlab.ui.control.Button
        EnergybalancemodelsTab          matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        SimpleEBMITab                   matlab.ui.container.Tab
        EBM                             matlab.ui.control.UIAxes
        TextArea_19                     matlab.ui.control.TextArea
        RunEBMmodelButton               matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        AlbedofeedbackButton            matlab.ui.control.StateButton
        StochasticfluctuationsButton    matlab.ui.control.StateButton
        OceanheatcapacityJm2K1SliderLabel  matlab.ui.control.Label
        Oceanheatcapacity               matlab.ui.control.Slider
        StarttemperatureCEditFieldLabel  matlab.ui.control.Label
        Starttemperature                matlab.ui.control.NumericEditField
        AlbedoLabel                     matlab.ui.control.Label
        Albedo                          matlab.ui.control.NumericEditField
        TransmissivityLabel             matlab.ui.control.Label
        Transmissivity                  matlab.ui.control.NumericEditField
        SimulationtimeyearsLabel        matlab.ui.control.Label
        years_EBM                       matlab.ui.control.NumericEditField
        SimpleEBMIITab_2                matlab.ui.container.Tab
        radiativeforcing_Axes           matlab.ui.control.UIAxes
        RunmodelButton                  matlab.ui.control.Button
        surfacetempanom_Axes            matlab.ui.control.UIAxes
        TextArea_28                     matlab.ui.control.TextArea
        MixedlayerdepthmSliderLabel     matlab.ui.control.Label
        MixedlayerdepthmSlider          matlab.ui.control.Slider
        ClimatesensitivityequilibriumSliderLabel  matlab.ui.control.Label
        ClimatesensitivityequilibriumSlider  matlab.ui.control.Slider
        DeepoceandepthmSliderLabel      matlab.ui.control.Label
        DeepoceandepthmSlider           matlab.ui.control.Slider
        NoisefactorSliderLabel          matlab.ui.control.Label
        NoisefactorSlider               matlab.ui.control.Slider
        VolcanicfactorSliderLabel       matlab.ui.control.Label
        VolcanicfactorSlider            matlab.ui.control.Slider
        SolarfactorSliderLabel          matlab.ui.control.Label
        SolarfactorSlider               matlab.ui.control.Slider
        GHGfactorSliderLabel            matlab.ui.control.Label
        GHGfactorSlider                 matlab.ui.control.Slider
        SulfateaerosolsfactorSliderLabel  matlab.ui.control.Label
        SulfateaerosolsfactorSlider     matlab.ui.control.Slider
    end

    
    properties (Access = private)
        %Property % Description
        data
        filename
        path
        customRCP
        customAMO
        customSunspot
        temp_future_custom
        Inputdata
        mdl
        years
        k
        l
        hPlotData
        counter
        country_counter
        blinking_lamp
    end
    
    methods (Access = private)
%         function RCP3P_Callback(app, hObject, eventdata, handles)
%             % hObject    handle to RCP3P (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             load climatechange_statistical_model
%             app.current_data = app.rcp3p;
%             hold on
%             plot(year_future2,app.current_data)
%         end
%         
%         function RCP45_Callback(app, hObject, eventdata, handles)
%             % hObject    handle to RCP45 (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             load climatechange_statistical_model
%             hold on
%             app.current_data = app.rcp45;
%             plot(year_future2,app.current_data)
%         end


        function blinking_lamp_Fcn(app,~,~)
             %Lampstatus = get(app.Lamp, 'Enable');
             if strcmp(app.Lamp.Enable,'on')
                       app.Lamp.Enable('off')     
             else 
                       app.Lamp.Enable('on')
             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CC_estimator_GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to CC_estimator_GUI (see VARARGIN)
%             load climatechange_statistical_model
%             app.rcp3p = temp_future_rcp3;
%             app.rcp45 = temp_future_rcp45;
%             app.rcp6 = temp_future_rcp6;
%             app.rcp85 = temp_future_rcp85;
%             
%             set(app.axes1, 'visible','on')
%             set(app.rcp_scenarios, 'visible','on')
%             set(app.run, 'visible','on')
%             set(app.information,'visible','on')
%             
%             % Set the current data value.
%             % app.current_data = app.rcp3p;
%             % plot(year_future2,app.current_data)
%             
%             % Choose default command line output for CC_estimator_GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
            app.data = load('climatechange_app_data.mat');
            app.counter=1;
            app.country_counter=1;
            
            % Create timer object
            app.blinking_lamp = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.2, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.blinking_lamp_Fcn);    % Specify callback function
        end

        % Callback function
        function pushbutton8_Callback(app, event)
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.axes1, 'visible','on')
            set(app.rcp_scenarios, 'visible','on')
            set(app.run, 'visible','on')
            set(app.information,'visible','off')
        end

        % Callback function
        function pushbutton9_Callback(app, event)
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.axes1, 'visible','off')
            set(app.rcp_scenarios, 'visible','off')
            set(app.run, 'visible','off')
            set(app.information,'visible','on')
        end

        % Callback function
        function run_Callback(app, event)
            % hObject    handle to run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %load climatechange_statistical_model
            %selected_scenario = get(app.rcp_scenarios,'SelectedObject')
            %selected_scenario1 = get(selected_scenario,'String');

            %end
        end

        % Callback function
        function rcp_scenariosSelectionChanged2(app, event)
            selectedButton = app.rcp_scenarios.SelectedObject;
            selected_scenario1 = selectedButton.Text;
            switch selected_scenario1
                case 'RCP2.6'
                    hold (app.axes1,'off')
                    h(1)=plot(app.axes1,app.data.year_future2,app.data.Ypred_rcp3,'LineWidth',1.5);
                    hold (app.axes1,'on')
                    h(2)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp3(:,1),'g--');
                    hold (app.axes1,'on')
                    h(3)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp3(:,2),'g--');
                    hold (app.axes1,'on')
                    h(4)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp3(:,1),'r--');
                    hold (app.axes1,'on')
                    h(5)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp3(:,2),'r--');
                    grid (app.axes1,'on')
                    xlabel(app.axes1,'Year')
                    ylabel(app.axes1,'Mean temperature anomaly (°C)')
                    legend(app.axes1,h([1 2 4]),'Modelled temperature anomaly','95% confidence interval','95% prediction interval','Location','southeast');
                case 'RCP4.5'
                    hold (app.axes1,'off')
                    h(1)=plot(app.axes1,app.data.year_future2,app.data.temp_future_rcp45,'LineWidth',1.5);
                    hold (app.axes1,'on')
                    h(2)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp45(:,1),'g--');
                    hold (app.axes1,'on')
                    h(3)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp45(:,2),'g--');
                    hold (app.axes1,'on')
                    h(4)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp45(:,1),'r--');
                    hold (app.axes1,'on')
                    h(5)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp45(:,2),'r--');
                    grid (app.axes1,'on')
                    xlabel(app.axes1,'Year')
                    ylabel(app.axes1,'Mean temperature anomaly (°C)')
                    legend(app.axes1,h([1 2 4]),'Modelled temperature anomaly','95% confidence interval','95% prediction interval','Location','southeast');
                case 'RCP6.0'
                   hold (app.axes1,'off')
                    h(1)=plot(app.axes1,app.data.year_future2,app.data.temp_future_rcp6,'LineWidth',1.5);
                    hold (app.axes1,'on')
                    h(2)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp6(:,1),'g--');
                    hold (app.axes1,'on')
                    h(3)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp6(:,2),'g--');
                    hold (app.axes1,'on')
                    h(4)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp6(:,1),'r--');
                    hold (app.axes1,'on')
                    h(5)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp6(:,2),'r--');
                    grid (app.axes1,'on')
                    xlabel(app.axes1,'Year')
                    ylabel(app.axes1,'Mean temperature anomaly (°C)')
                    legend(app.axes1,h([1 2 4]),'Modelled temperature anomaly','95% confidence interval','95% prediction interval','Location','southeast');
                case 'RCP8.5'
                    hold (app.axes1,'off')
                    h(1)=plot(app.axes1,app.data.year_future2,app.data.temp_future_rcp85,'LineWidth',1.5);
                    hold (app.axes1,'on')
                    h(2)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp85(:,1),'g--');
                    hold (app.axes1,'on')
                    h(3)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp85(:,2),'g--');
                    hold (app.axes1,'on')
                    h(4)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp85(:,1),'r--');
                    hold (app.axes1,'on')
                    h(5)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp85(:,2),'r--');
                    grid (app.axes1,'on')
                    xlabel(app.axes1,'Year')
                    ylabel(app.axes1,'Mean temperature anomaly (°C)')
                    legend(app.axes1,h([1 2 4]),'Modelled temperature anomaly','95% confidence interval','95% prediction interval','Location','southeast');
                case 'All RCP'
                    hold (app.axes1,'off')
                    plot(app.axes1,app.data.year, app.data.temp,'o')
                    hold(app.axes1,'on');
                    plot(app.axes1,app.data.year_future2, app.data.temp_future_rcp3,'LineWidth',1.5)
                    hold(app.axes1,'on');
                    plot(app.axes1,app.data.year_future2,app.data.temp_future_rcp45,'LineWidth',1.5)
                    hold(app.axes1,'on');
                    plot(app.axes1,app.data.year_future2,app.data.temp_future_rcp6,'LineWidth',1.5)
                    hold(app.axes1,'on');
                    plot(app.axes1,app.data.year_future2,app.data.temp_future_rcp85,'LineWidth',1.5)
                    grid (app.axes1,'on')
                    xlabel(app.axes1,'Year')
                    legend(app.axes1,'on');
                    legend(app.axes1,'Measured','RCP2.6','RCP4.5','RCP6','RCP8.5','Location','northwest')
                    ylabel(app.axes1,'Mean temperature anomaly (°C)')
            end
        end

        % Button pushed function: ExportInputdataButton
        function ExportInputdataButtonPushed(app, event)
            T = table(app.data.year,app.data.X(:,1),app.data.X(:,2),app.data.X(:,3),app.data.X(:,4),'VariableNames',{'Year','CO2','Sunspot_Number','AMO','Surface_solar_rad'});
            writetable(T,'Input_data.xls')
        end

        % Selection changed function: rcp_scenarios_2
        function rcp_scenarios_2SelectionChanged(app, event)
            selectedButton = app.rcp_scenarios_2.SelectedObject; % co2 concentrations
            selected_scenario1 = selectedButton.Text;
            switch selected_scenario1
                case 'RCP2.6'
                    hold (app.axes2,'off')
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp3,'LineWidth',1.5)
                    grid (app.axes2,'on')
                    xlabel(app.axes2,'Year')
                    ylabel(app.axes2,'CO_2 concentration (ppm)')
                    legend(app.axes2,'off');
                    
                    app.TextArea_12.Value = 'Hint: In the RCP2.6 scenario a technology development is assumed that allow to extract CO2 out of the atmosphere which lead to overall negative CO2 emissions.';
                case 'RCP4.5'
                    hold (app.axes2,'off')
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp45,'LineWidth',1.5)
                    grid (app.axes2,'on')
                    xlabel(app.axes2,'Year')
                    ylabel(app.axes2,'CO_2 concentration (ppm)')
                    legend(app.axes2,'off');
                    
                    app.TextArea_12.Value = 'Hint: This scenario assumes rising GHG concentrations until a radiative forcing of 4.5 W per square meter is reached. More info in the Ebook.';
                case 'RCP6.0'
                    hold (app.axes2,'off')
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp6,'LineWidth',1.5)
                    grid (app.axes2,'on')
                    xlabel(app.axes2,'Year')
                    ylabel(app.axes2,'CO_2 concentration (ppm)')
                    legend(app.axes2,'off');
                    
                    app.TextArea_12.Value = 'Hint: This scenario assumes rising GHG concentrations until a radiative forcing of 6 W per square meter is reached. More info in the Ebook.';
                case 'RCP8.5'
                    hold (app.axes2,'off')
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp85,'LineWidth',1.5)
                    grid (app.axes2,'on')
                    xlabel(app.axes2,'Year')
                    ylabel(app.axes2,'CO_2 concentration (ppm)')
                    legend(app.axes2,'off');
                    
                    app.TextArea_12.Value = 'Hint: This scenario assumes rising GHG concentrations until a radiative forcing of 8.5 W per square meter is reached. More info in the Ebook.';
                case 'All RCP'
                    plot(app.axes2,app.data.year_future2, app.data.co2_rcp3,'LineWidth',1.5)
                    hold(app.axes2,'on');
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp45,'LineWidth',1.5)
                    hold(app.axes2,'on');
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp6,'LineWidth',1.5)
                    hold(app.axes2,'on');
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp85,'LineWidth',1.5)
                    grid (app.axes2,'on')
                    xlabel(app.axes2,'Year')
                    legend(app.axes2,'on');
                    legend(app.axes2,'RCP2.6','RCP4.5','RCP6','RCP8.5','Location','northwest')
                    ylabel(app.axes2,'CO_2 concentration (ppm)')
                    
                    app.TextArea_12.Value = 'Hint: More info concerning all representative concentration pathway (RCP) scenarios can be found in the Ebook accompagnied with this App.';
            end
        end

        % Selection changed function: rcp_scenarios_3
        function rcp_scenarios_3SelectionChanged(app, event)
            selectedButton = app.rcp_scenarios_3.SelectedObject;
            selected_scenario1 = selectedButton.Text;
            switch selected_scenario1
                case 'RCP2.6'
                    hold (app.axes3,'off')
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp3_em,'LineWidth',1.5)
                    grid (app.axes3,'on')
                    xlabel(app.axes3,'Year')
                    ylabel(app.axes3,'CO_2 emissions (Gt year^{-1})')
                    legend(app.axes3,'off');
                    
                    app.TextArea_2.Value = 'Hint: In the RCP2.6 scenario a technology development is assumed that allow to extract CO2 out of the atmosphere which lead to overall negative CO2 emissions.';
                case 'RCP4.5'
                    hold (app.axes3,'off')
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp45_em,'LineWidth',1.5)
                    grid (app.axes3,'on')
                    xlabel(app.axes3,'Year')
                    ylabel(app.axes3,'CO_2 emissions (Gt year^{-1})')
                    legend(app.axes3,'off');
                    
                    app.TextArea_2.Value = 'Hint: This scenario assumes rising GHG concentrations until a radiative forcing of 4.5 W per square meter is reached. More info in the Ebook.';
                case 'RCP6.0'
                    hold (app.axes3,'off')
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp6_em,'LineWidth',1.5)
                    grid (app.axes3,'on')
                    xlabel(app.axes3,'Year')
                    ylabel(app.axes3,'CO_2 emissions (Gt year^{-1})')
                    legend(app.axes3,'off');
                    
                    app.TextArea_2.Value = 'Hint: This scenario assumes rising GHG concentrations until a radiative forcing of 6 W per square meter is reached. More info in the Ebook.';
                case 'RCP8.5'
                    hold (app.axes3,'off')
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp85_em,'LineWidth',1.5)
                    grid (app.axes3,'on')
                    xlabel(app.axes3,'Year')
                    ylabel(app.axes3,'CO_2 emissions (Gt year^{-1})')
                    legend(app.axes3,'off');
                    
                    app.TextArea_2.Value = 'Hint: This scenario assumes rising GHG concentrations until a radiative forcing of 8.5 W per square meter is reached. More info in the Ebook.';
                case 'All RCP'
                    plot(app.axes3,app.data.year_future2, app.data.co2_rcp3_em,'LineWidth',1.5)
                    hold(app.axes3,'on');
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp45_em,'LineWidth',1.5)
                    hold(app.axes3,'on');
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp6_em,'LineWidth',1.5)
                    hold(app.axes3,'on');
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp85_em,'LineWidth',1.5)
                    grid (app.axes3,'on')
                    xlabel(app.axes3,'Year')
                    legend(app.axes3,'on');
                    legend(app.axes3,'RCP2.6','RCP4.5','RCP6','RCP8.5','Location','northwest')
                    ylabel(app.axes3,'CO_2 emissions (Gt year^{-1})')
                    
                    app.TextArea_2.Value = 'Hint: More info concerning all representative concentration pathway (RCP) scenarios can be found in the Ebook accompagnied with this App.';
            end
        end

        % Callback function
        function PlottemperatureButtonPushed(app, event)
            plot(app.axes4,app.data.year,app.data.temp,'o')
            hold(app.axes4,'on')
            plot(app.axes4,app.data.years_recent,app.data.temp_recent,'o')
            hold(app.axes4,'on')
            plot(app.axes4,app.data.year,app.data.temp_hat_co2_AMO_sunspots)
            hold(app.axes4,'on')
            plot(app.axes4,app.data.years_recent,app.data.temp_model_recent)
            xlabel(app.axes4,'Years')
            ylabel(app.axes4,'Temperature anomaly (°C)')
            legend(app.axes4,'Measured','Measured (not used for training/validation)','Model','Model 2011-2018 (sunspot number linearly extrapolated)','Location','northwest')
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            selected_tab = selectedTab.Title;
            switch selected_tab
                case 'CO2 concentrations'
                    hold (app.axes2,'off')
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp3,'LineWidth',1.5)
                    grid (app.axes2,'on')
                    xlabel(app.axes2,'Year')
                    ylabel(app.axes2,'CO_2 concentration (ppm)')
                    legend(app.axes2,'off');
                case 'CO2 emissions'
                    hold (app.axes3,'off')
                    plot(app.axes3,app.data.year_future2,app.data.co2_rcp3_em,'LineWidth',1.5)
                    grid (app.axes3,'on')
                    xlabel(app.axes3,'Year')
                    ylabel(app.axes3,'CO_2 emissions (Gt year^{-1})')
                    legend(app.axes3,'off');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            selected_tab = selectedTab.Title;
            switch selected_tab
                case 'Future Temperature'
                    hold (app.axes1,'off')
                    h(1)=plot(app.axes1,app.data.year_future2,app.data.temp_future_rcp3,'LineWidth',1.5);
                    hold (app.axes1,'on')
                    h(2)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp3(:,1),'g--');
                    hold (app.axes1,'on')
                    h(3)=plot(app.axes1,app.data.year_future2,app.data.YCI_curve_rcp3(:,2),'g--');
                    hold (app.axes1,'on')
                    h(4)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp3(:,1),'r--');
                    hold (app.axes1,'on')
                    h(5)=plot(app.axes1,app.data.year_future2,app.data.YCI_obs_rcp3(:,2),'r--');
                    grid (app.axes1,'on')
                    xlabel(app.axes1,'Year')
                    ylabel(app.axes1,'Mean temperature anomaly (°C)')
                    legend(app.axes1,h([1 2 4]),'Modelled temperature anomaly','95% confidence interval','95% prediction interval','Location','southeast');
               case 'Future CO2'
                    hold (app.axes2,'off')
                    plot(app.axes2,app.data.year_future2,app.data.co2_rcp3,'LineWidth',1.5)
                    grid (app.axes2,'on')
                    xlabel(app.axes2,'Year')
                    ylabel(app.axes2,'CO_2 concentration (ppm)')
                    legend(app.axes2,'off');
                case 'Historical data'
                   str = ['El Ni' char(0241) 'o (°C)']; % tilde char
                   plot(app.Elnino_Axes,app.data.Year_elnino,app.data.elnino)
                   xlabel(app.Elnino_Axes,'Year')
                   ylabel(app.Elnino_Axes,str)
                   set(app.Elnino_Axes,'FontSize',14)
                case 'Model evaluation'
                   hold (app.scatterplot,'off')
                   scatter(app.scatterplot,app.data.temp_hat_co2_AMO_sunspots,app.data.temp)
                   hold(app.scatterplot,'on')
                   h(1) = plot(app.scatterplot,app.data.temp_hat_co2_AMO_sunspots,app.data.y_hat,'LineWidth',1.5);
                   hold (app.scatterplot,'on')
                   h(2) = plot(app.scatterplot,-0.6:0.1:0.8,-0.6:0.1:0.8,'--','LineWidth',1.5,'Color',[0.5 0.5 0.5]);
                   xlabel(app.scatterplot,'Modelled temperature anomaly (°C)')
                   ylabel(app.scatterplot,'Temperature anomaly data (°C)')
                   legend(app.scatterplot,h([1 2]),'y = 1.0*x-2.6e-05','1:1 line','Location','northwest');
                   
            end
        end

        % Button pushed function: Scatterplot_Button
        function Scatterplot_ButtonPushed(app, event)
                  scatter(app.scatterplot,app.data.temp_hat_co2_AMO_sunspots,app.data.temp)
                  hold (app.scatterplot,'on')
                  plot(app.scatterplot,-0.5:0.1:1,-0.5:0.1:1)
                  xlabel(app.scatterplot,'Modelled temperature anomaly (°C)')
                  ylabel(app.scatterplot,'Temperature anomaly data (°C)')
                  legend(app.scatterplot,'Data','1:1 line','Location','northwest');
        end

        % Button pushed function: residuals_Button
        function residuals_ButtonPushed(app, event)
            histogram(app.residuals,app.data.temp_hat_co2_AMO_sunspots-app.data.temp)
            xlabel(app.residuals,'Residuals (°C)')
            ylabel(app.residuals,'Count')
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            selected_tab = selectedTab.Title;
            switch selected_tab
                case 'Temperature'
                    hold (app.axes4,'off')
                    plot(app.axes4,app.data.year,app.data.temp,'o')
                    hold(app.axes4,'on')
                    plot(app.axes4,app.data.years_recent,app.data.temp_recent,'o')
                    hold(app.axes4,'on')
                    plot(app.axes4,app.data.year,app.data.temp_hat_co2_AMO_sunspots)
                    hold(app.axes4,'on')
                    plot(app.axes4,app.data.years_recent,app.data.temp_model_recent)
                    xlabel(app.axes4,'Years')
                    ylabel(app.axes4,'Temperature anomaly (°C)')
                    legend(app.axes4,'Measured','Measured (not used for training/validation)','Model','Model 2011-2018 (smoothed sunspot number linearly extrapolated)','Location','northwest')
                case 'AMO'
                    hold (app.axes4_2,'off')
                    plot(app.axes4_2,app.data.year,app.data.AMO,'o')
                    xlabel(app.axes4_2,'Years')
                    ylabel(app.axes4_2,'AMO')
                case 'Sunspot number'
                    hold (app.axes4_3,'off')
                    plot(app.axes4_3,app.data.year,app.data.sunspots,'o')
                    hold (app.axes4_3,'on')
                    plot(app.axes4_3,app.data.year,app.data.sunspots_smoothed)
                    xlabel(app.axes4_3,'Years')
                    ylabel(app.axes4_3,'Sunspot number')
                    legend(app.axes4_3,'Sunspot number','Smoothed sunspots number')
                case 'CO₂'
                    hold (app.axes4_4,'off')
                    plot(app.axes4_4,app.data.year,app.data.co2,'o')
                    xlabel(app.axes4_4,'Years')
                    ylabel(app.axes4_4,'CO2 (ppm)')
            end
        end

        % Selection changed function: rcp_scenarios_4
        function rcp_scenarios_4SelectionChanged(app, event)
            selectedButton = app.rcp_scenarios_4.SelectedObject;
            selected_scenario1 = selectedButton.Text;
            switch selected_scenario1
                case 'RCP3-PD'
                    
                case 'RCP4.5'
                    hold (app.cmip5, 'off')
                    plot(app.cmip5,app.data.year_future2,app.data.cmip5RCP45)
                    hold(app.cmip5, 'on')
                    plot(app.cmip5,app.data.year_future2,app.data.temp_future_rcp45,'LineWidth',2,'Color','k')
                    hold(app.cmip5, 'on')
                    plot(app.cmip5,app.data.years_1980_2018,app.data.temp_1980_2018,'o','Color','k')
                    xlabel(app.cmip5,'Years')
                    ylabel(app.cmip5,'Temperature anomaly (°C)')
                case 'RCP6.0'
                    hold (app.cmip5, 'off')
                    plot(app.cmip5,app.data.year_future2,app.data.cmip5RCP60)
                    hold(app.cmip5, 'on')
                    plot(app.cmip5,app.data.year_future2,app.data.temp_future_rcp6,'LineWidth',2,'Color','k')
                    hold(app.cmip5, 'on')
                    plot(app.cmip5,app.data.years_1980_2018,app.data.temp_1980_2018,'o','Color','k')
                    xlabel(app.cmip5,'Years')
                    ylabel(app.cmip5,'Temperature anomaly (°C)')
                case 'RCP8.5'
                    hold (app.cmip5, 'off')
                    plot(app.cmip5,app.data.year_future2,app.data.cmip5RCP85)
                    hold(app.cmip5, 'on')
                    plot(app.cmip5,app.data.year_future2,app.data.temp_future_rcp85,'LineWidth',2,'Color','k')
                    hold(app.cmip5, 'on')
                    plot(app.cmip5,app.data.years_1980_2018,app.data.temp_1980_2018,'o','Color','k')
                    xlabel(app.cmip5,'Years')
                    ylabel(app.cmip5,'Temperature anomaly (°C)')
            end
        end

        % Button pushed function: ImportRCPButton
        function ImportRCPButtonPushed(app, event)
            [app.filename,app.path] = uigetfile('*.csv');
            try
            app.customRCP = csvread(fullfile(app.path,app.filename));
            catch
                
            end
        end

        % Button pushed function: PlotRCPButton
        function PlotRCPButtonPushed(app, event)
            try
            plot(app.modifiedmodel,app.data.year_future2,app.customRCP)
            xlabel(app.modifiedmodel,'Years')
            ylabel(app.modifiedmodel,'CO_2 concentration (ppm)')
            title(app.modifiedmodel,'Congrats you have made your own RCP scenario!')
            catch
                f = uifigure;
                uialert(f,'You have to import your CO2 data first','No data');
            end
        end

        % Button pushed function: ImportAMOButton
        function ImportAMOButtonPushed(app, event)
            [app.filename,app.path] = uigetfile('*.csv');
            try
            app.customAMO = csvread(fullfile(app.path,app.filename));
            catch
         
            end
        end

        % Button pushed function: ImportsunspotnumbersButton
        function ImportsunspotnumbersButtonPushed(app, event)
            [app.filename,app.path] = uigetfile('*.csv');
            try
            app.customSunspot = csvread(fullfile(app.path,app.filename));
            catch
                
            end
        end

        % Button pushed function: PlotAMOButton
        function PlotAMOButtonPushed(app, event)
            try
            plot(app.modifiedmodel,app.data.year_future2,app.customAMO)
            xlabel(app.modifiedmodel,'Years')
            ylabel(app.modifiedmodel,'AMO')
            title(app.modifiedmodel,'Congrats you have made your own AMO scenario!')
            catch
                f = uifigure;
                uialert(f,'You have to import your AMO data first','No data');
            end
        end

        % Button pushed function: PlotsunspotnumbersButton
        function PlotsunspotnumbersButtonPushed(app, event)
            try
            plot(app.modifiedmodel,app.data.year_future2,app.customSunspot)
            xlabel(app.modifiedmodel,'Years')
            ylabel(app.modifiedmodel,'Sunspot numbers')
            title(app.modifiedmodel,'Congrats you have made your own sunspot numbers scenario!')
            catch
                f = uifigure;
                uialert(f,'You have to import your sunspot number data first','No data');
            end
        end

        % Button pushed function: RuncustommodelButton
        function RuncustommodelButtonPushed(app, event)
            if ~isempty(app.customRCP) && isempty(app.customAMO) && isempty(app.customSunspot)
                app.temp_future_custom = -3.4159 + 0.0099996 .* app.customRCP'  + 0.0019103 .* app.data.sunspots_const + 0.41685 .* app.data.AMO_hat;
            
            elseif ~isempty(app.customRCP) && ~isempty(app.customAMO) && isempty(app.customSunspot)
                app.temp_future_custom = -3.4159 + 0.0099996 .* app.customRCP'  + 0.0019103 .* app.data.sunspots_const + 0.41685 .* app.customAMO';
            elseif ~isempty(app.customRCP) && ~isempty(app.customAMO) && ~isempty(app.customSunspot)
                app.temp_future_custom = -3.4159 + 0.0099996 .* app.customRCP'  + 0.0019103 .* app.customSunspot' + 0.41685 .* app.customAMO';
            elseif isempty(app.customRCP) && ~isempty(app.customAMO) && ~isempty(app.customSunspot)
                app.temp_future_custom = -3.4159 + 0.0099996 .* co2_rcp45' + 0.0019103 .* app.customSunspot' + 0.41685 .* app.customAMO';
            elseif isempty(app.customRCP) && isempty(app.customAMO) && ~isempty(app.customSunspot)
                app.temp_future_custom = -3.4159 + 0.0099996 .* co2_rcp45' + 0.0019103 .* app.customSunspot' + 0.41685 .* app.data.AMO_hat;
            elseif isempty(app.customRCP) && ~isempty(app.customAMO) && isempty(app.customSunspot)
                app.temp_future_custom = -3.4159 + 0.0099996 .* co2_rcp45' + 0.0019103 .* app.data.sunspots_const + 0.41685 .* app.customAMO';
            end
                
                hold(app.modifiedmodel,'off')
                plot(app.modifiedmodel,app.data.year_future2,app.temp_future_custom)
                xlabel(app.modifiedmodel,'Years')
                ylabel(app.modifiedmodel,'Temperature anomaly (°C)')
        end

        % Button pushed function: ImportinputdataButton
        function ImportinputdataButtonPushed(app, event)
            [app.filename,app.path] = uigetfile('*.csv');
            try
            app.Inputdata = csvread(fullfile(app.path,app.filename),1,0);
            app.years = app.Inputdata(:,1);
            app.k = find(app.data.year == app.years(1));
            app.l = find(app.data.year == app.years(end));
            msgbox('Data imported');
            catch
                f = uifigure;
                uialert(f,'Something went wrong','No data');
            end 
        end

        % Button pushed function: TrainmodelButton
        function TrainmodelButtonPushed(app, event)
            if ~isempty(app.Inputdata)
               try

                app.Lamp.Color = 'red';
                app.Lamp.Visible = 'on';
                app.Lamp.Enable = 'off';
                
                start(app.blinking_lamp)
               
                
                app.mdl = stepwiselm(app.Inputdata(:,2:end),app.data.temp(app.k:app.l),'PEnter',0.05);                
                filename = 'model_test.xlsx';
                sheet = 'Coefficients';
                writetable(app.mdl.Coefficients,fullfile(app.path,filename),'Sheet',sheet,'WriteRowNames',true)
                stop(app.blinking_lamp)

                app.Lamp.Enable = 'on';
                app.Lamp.Color = 'green';
                msgbox('Model trained. Results were exported to model_test.xlsx');
            catch e %e is an MException struct
                    msgbox(e.message)
            end
                
            else
                f = uifigure;
                uialert(f,'You have to import your input data first','No data');
            end
        end

        % Button pushed function: TestmodelButton
        function TestmodelButtonPushed(app, event)

            str = num2str(app.mdl.RMSE);
            scatter(app.modmodel,app.mdl.Fitted,app.data.temp)
            title(app.modmodel,'Your model results')
            xlabel(app.modmodel,'Modelled temperature anomalies (°C)')
            ylabel(app.modmodel,'Measured temperature anomalies (°C)')
            
            plot(app.modmodel_2,app.data.year,app.data.temp,'o')
            hold(app.modmodel_2,'on')
            plot(app.modmodel_2,app.data.year,app.data.temp_hat_co2_AMO_sunspots)
            hold(app.modmodel_2,'on')
            plot(app.modmodel_2,app.data.year,app.mdl.Fitted)
            legend(app.modmodel_2,'Measured data','Original model','Your model','Location','northwest')
            xlabel(app.modmodel_2,'Year')
            ylabel(app.modmodel_2,'Temperature anomaly (°C)')
        end

        % Button pushed function: ExportRCPdataButton
        function ExportRCPdataButtonPushed(app, event)
            T = table(app.data.year_future2',app.data.co2_rcp3,app.data.co2_rcp45,app.data.co2_rcp6,app.data.co2_rcp85,'VariableNames',{'Year','RCP3PD','RCP45','RCP60','RCP85'});
            writetable(T,'RCP_data.xls')
        end

        % Callback function
        function TabGroup4SelectionChanged(app, event)
            selectedTab = app.TabGroup4.SelectedTab;
            selected_tab = selectedTab.Title;
            switch selected_tab
                case 'Influence of predictors'
                    plotSlice(app.predictorsweight,app.mdl)
            end
        end

        % Button pushed function: ComparewithRCPscenariosButton
        function ComparewithRCPscenariosButtonPushed(app, event)
            
            hold (app.modifiedmodel,'on')
            plot(app.modifiedmodel,app.data.year, app.data.temp,'o')
            hold(app.modifiedmodel,'on');
            plot(app.modifiedmodel,app.data.year_future2, app.data.Ypred_rcp3,'LineWidth',1.5)
            hold(app.modifiedmodel,'on');
            plot(app.modifiedmodel,app.data.year_future2,app.data.temp_future_rcp45,'LineWidth',1.5)
            hold(app.modifiedmodel,'on');
            plot(app.modifiedmodel,app.data.year_future2,app.data.temp_future_rcp6,'LineWidth',1.5)
            hold(app.modifiedmodel,'on');
            plot(app.modifiedmodel,app.data.year_future2,app.data.temp_future_rcp85,'LineWidth',1.5)
            grid (app.modifiedmodel,'on')
            xlabel(app.modifiedmodel,'Year')
            legend(app.modifiedmodel,'on');
            legend(app.modifiedmodel,'Custom RCP','Measured','RCP3-PD','RCP4.5','RCP6','RCP8.5','Location','northwest')
            ylabel(app.modifiedmodel,'Mean temperature anomaly (°C)')
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Button pushed function: RunEBMmodelButton
        function RunEBMmodelButtonPushed(app, event)
            tage = 10;
            dt = 60*60*24*365.25;
            Cw = app.Oceanheatcapacity.Value; % Wärmekapazität für eine mittlere Ozeanschichtdicke von 50m = ozeanische Deckschicht (J m-2 K-1)
            sigma = 5.67*10^(-8);
            em=0.95;
            alpha=app.Albedo.Value;
            years = app.years_EBM.Value;
            tau(1:years) = app.Transmissivity.Value;
            sun_in=342;

            T(1)=app.Starttemperature.Value+273.15;
            
            if app.StochasticfluctuationsButton.Value==1
               a = 0.03*0.64;
               b = 0.64;
               tau = a.*randn(years,1) + b; % generates normally distributed values with mean b and standard deviation a
            end
            
            
            for i = 2:years
                
                if app.AlbedofeedbackButton.Value==1
                alpha = 0.3*(1-0.06*tanh(1.4*(T(i-1)-288.620)));    
                end
                
                T(i) = T(i-1)+ dt/Cw * (sun_in-alpha*sun_in-em*sigma*T(i-1)^4*tau(i));
            end
            hold(app.EBM,'on')
            app.hPlotData(app.counter) = plot(app.EBM,T-273.15);
            ylabel(app.EBM,'Global mean temperature (°C)')
            xlabel(app.EBM,'year')
            app.counter = app.counter+1;
            
            figure()
            histogram(T)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            try
                delete(app.hPlotData)
                app.counter = 1;
            catch
            end
        end

        % Button pushed function: RunmodelButton
        function RunmodelButtonPushed(app, event)

            VolcanicforcingWm2 = app.data.EBMData.VolcanicforcingWm2;
            NoiseWm2 = app.data.EBMData.NoiseWm2;
            SolarforcingWm2 = app.data.EBMData.SolarforcingWm2;
            GHGforcingWm2 = app.data.EBMData.GHGforcingWm2;
            SO4forcingWm2 = app.data.EBMData.SO4forcingWm2;
            Year = app.data.EBMData.Year;
            ObservedtempanomK1 = app.data.EBMData.ObservedtempanomK1;
                        
            phi = 0.7; %Fraction of Earth covered by ocean
            Cv = 3997500.00; %Volumetric heat capacity of sea water
            Q2xCO2 = 3.71; %Radiative forcing for a doubling of CO2
            spy = 31557600.00;  %Seconds per year

            % Möglicherweise variable Daten
            Hmix = app.MixedlayerdepthmSlider.Value; %Mixed layer depth (40 to 100m)
            T0 = -0.10; %Surface/mixed layer temperature anomaly at the start of the simulation
            %(-0.5 to +0.5 K)
            s = app.ClimatesensitivityequilibriumSlider.Value; %Climate sensitivity expressed as equilibrium temperature rise following a doubling of CO2
            %(1.5 to 4.5 K/Q2xCO2)
            kappa = 1.00; %Diffusivity between mixed layer and deep ocean boxes (0.3 to 3.0 W/m**2/K)
            Hdeep = app.DeepoceandepthmSlider.Value; %Deep ocean depth (100 to 1000m)
            Td0 = -0.10; %Deep ocean temperature anomaly at the start of the simulation (-0.5 to +0.5 K)

            % Parameters that are derived from others
            lambda = Q2xCO2/s; %W/m**2/K
            
            Sinusoid = 0; %range = 0
            Noise = app.NoisefactorSlider.Value; %range = 0 to 1
            Volcanic = app.VolcanicfactorSlider.Value; %range= 0.7 to 1.3
            Solar = app.SolarfactorSlider.Value; %range = 0.2 to 1.0
            GHG = app.GHGfactorSlider.Value; %range = 0.9 to 1.1
            Sulphate_aerosols = app.SulfateaerosolsfactorSlider.Value; %range = 0.4 to 1.7
            
            Ampl = 0.1725;
            Sinusforcing = zeros(length(Year),1);
            for i=1:242
                Sinusforcing (i,1) = Ampl * sin (Year(i,1) * 2 * pi/11);
            end
            Sinusiodforcing = Sinusforcing.*Sinusoid;
            Noiseforcing = NoiseWm2.*Noise;
            Volcanicforcing = VolcanicforcingWm2.*Volcanic;
            Solarforcing = SolarforcingWm2.*Solar;
            GHGforcing = GHGforcingWm2.*GHG;
            SO4forcing = SO4forcingWm2.*Sulphate_aerosols;
            
            % radiative forcing
            radiationforcing = Sinusiodforcing + Volcanicforcing + Solarforcing + GHGforcing + SO4forcing+ Noiseforcing;
            
            plot(app.radiativeforcing_Axes,Year,radiationforcing,'LineStyle','-','LineWidth',2);
            ylabel(app.radiativeforcing_Axes,'Radiative forcing (W m^{-2})')
            xlabel(app.radiativeforcing_Axes,'Year')
            %xlim([1765,2006])
            

            Surfacetempanom (1,1) = -0.1;
            Deepoceantemp (1,1) = -0.1;

            
            HFdeep (1,1) = kappa * (Surfacetempanom(1) - Deepoceantemp(1));
            Annualdeepoceantemp (1,1) = spy * HFdeep (1,1) /(Cv * Hdeep);
            Excess (1,1)= lambda*Surfacetempanom;
            Annualtempchange = spy * (radiationforcing(1,1) - Excess(1,1) -(phi *HFdeep)) /(phi*Cv*Hmix);

            for i=2:242
                Surfacetempanom (i,1) = Surfacetempanom(i-1) + Annualtempchange(i-1);
                Deepoceantemp(i,1)  = Deepoceantemp(i-1) + Annualdeepoceantemp(i-1);
                HFdeep (i,1) = kappa * (Surfacetempanom (i,1) - Deepoceantemp (i,1));
                Annualdeepoceantemp (i,1) = spy * HFdeep (i,1) /(Cv * Hdeep);%(spy * HFdeep (i,1) /Cv * Hdeep);
                Excess (i,1) = lambda * Surfacetempanom (i,1);
                Annualtempchange(i,1) = spy * (radiationforcing(i,1) - Excess(i,1) -(phi *HFdeep(i,1))) /(phi*Cv*Hmix);
            end

            mean1= mean(Surfacetempanom(84:135));
            Simultempanom= Surfacetempanom-mean1;

            obs_mean = -0.343;
            observedtemp = ObservedtempanomK1 - obs_mean;
            
            plot(app.surfacetempanom_Axes,Year,Simultempanom,'Color',[1 0 0],'Linestyle','-','Linewidth',2)
            hold(app.surfacetempanom_Axes,'on')
            plot(app.surfacetempanom_Axes,Year,observedtemp,'Color',[1 0 0],'Linestyle',':','Linewidth',2)
            hold(app.surfacetempanom_Axes,'off')
            ylabel(app.surfacetempanom_Axes,'Temperatur (K)');
            xlabel(app.surfacetempanom_Axes,'Year');
            legend(app.surfacetempanom_Axes,'Simulated temp.anomaly','Observed temp.anomaly','Location','NorthEast', 'Orientation','horizontal');
            
        end

        % Button pushed function: ShowbudgetButton
        function ShowbudgetButtonPushed(app, event)
%             %% Setup the Import Options
%             opts = spreadsheetImportOptions("NumVariables", 6);
% 
%             % Specify sheet and range
%             opts.Sheet = "Population";
%             opts.DataRange = "A2:F233";
% 
%             % Specify column names and types
%             opts.VariableNames = ["Country", "Population_2019", "Population_2018", "BudgetGtonCO2", "Emissions2018", "Yearstozeroemissions"];
%             opts.VariableTypes = ["string", "double", "double", "double", "double", "double"];
%             opts = setvaropts(opts, 1, "WhitespaceRule", "preserve");
%             opts = setvaropts(opts, 1, "EmptyFieldRule", "auto");
% 
%             % Import the data
%             CO2_budget_table = readtable("\\klima-nas\home\Projekte\ClimateChange\Country_CO2_emissions_2016_calculator.xlsx", opts, "UseExcel", false);
% 
% 
%             %% Clear temporary variables
%             clear opts
%             
%             opts = spreadsheetImportOptions("NumVariables", 50);
% 
%             % Specify sheet and range
%             opts.Sheet = "CO2 emissions timeline";
%             opts.DataRange = "A2:AX214";
% 
%             % Specify column names and types
%             opts.VariableNames = ["country_name", "VarName2", "VarName3", "VarName4", "VarName5", "VarName6", "VarName7", "VarName8", "VarName9", "VarName10", "VarName11", "VarName12", "VarName13", "VarName14", "VarName15", "VarName16", "VarName17", "VarName18", "VarName19", "VarName20", "VarName21", "VarName22", "VarName23", "VarName24", "VarName25", "VarName26", "VarName27", "VarName28", "VarName29", "VarName30", "VarName31", "VarName32", "VarName33", "VarName34", "VarName35", "VarName36", "VarName37", "VarName38", "VarName39", "VarName40", "VarName41", "VarName42", "VarName43", "VarName44", "VarName45", "VarName46", "VarName47", "VarName48", "VarName49", "VarName50"];
%             opts.VariableTypes = ["string", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
%             opts = setvaropts(opts, 1, "WhitespaceRule", "preserve");
%             opts = setvaropts(opts, 1, "EmptyFieldRule", "auto");
% 
%             % Import the data
%             CO2_emissions_timeline = readtable("\\klima-nas\home\Projekte\ClimateChange\Country_CO2_emissions_2016_calculator.xlsx", opts, "UseExcel", false);
% 
% 
%             %% Clear temporary variables
%             clear opts
            
            global_budget = app.Globalbudget.Value;
            Global_population = 7600000000;
            k = find(app.data.CO2_budget_table.Country == app.CountryDropDown.Value);
            
            Budget = global_budget*app.data.CO2_budget_table.Population_2018(k)/Global_population;
            % following calculation is using a triangle to calculate the
            % years to 0 emissions
            Yearstozeroemissions = Budget*2/(app.data.CO2_budget_table.Emissions2018(k)/1000);
            app.BudgetGtonCO2TextArea.Value=num2str(Budget);
            app.Yearstoemissions.Value=num2str(Yearstozeroemissions);
            
            k = find(app.data.CO2_emissions_timeline.country_name == app.CountryDropDown.Value);
            
            Y = table2array(app.data.CO2_emissions_timeline(k,32:end));
            Y1 = [table2array(app.data.CO2_emissions_timeline(k,end)),0];
            X = 2000:2018;
            X1 = [2018,2018+str2double(app.Yearstoemissions.Value)];
%             length(X)
%             length(Y)
            
            if app.keepthecurrentplots.Value==1
               hold(app.CO2budget_Axes,'on')
               app.country_counter = app.country_counter+1;
               country{app.country_counter} = app.CountryDropDown.Value;
            else
               hold(app.CO2budget_Axes,'off')
               app.country_counter = 1;
               country{app.country_counter} = app.CountryDropDown.Value;
            end
            
            colors = [205/250,88/250,67/250;...
                      118/250,176/250,64/250;...
                      193/250,90/250,189/250;...
                      83/250 169/250 122/250;...
                      118/250 106/250 200/250;...
                      210/250 159/250 71/250;...
                      101/250 155/250 211/250;...
                      133/250 116/250 51/250;...
                      197/250 93/250 134/250];
            
            plot(app.CO2budget_Axes,X,Y,'--','Color',colors(app.country_counter,:),'LineWidth',2,'DisplayName',strcat(country{app.country_counter},' historic emissions'))
            hold(app.CO2budget_Axes,'on')
            plot(app.CO2budget_Axes,X1,Y1,'Color',colors(app.country_counter,:),'LineWidth',2,'DisplayName',strcat(country{app.country_counter},' emission pathway'))
            xlabel(app.CO2budget_Axes,'Year')
            ylabel(app.CO2budget_Axes,'Emissions (Mton CO_2)')
            legend(app.CO2budget_Axes,'-DynamicLegend')
            
        end

        % Selection change function: TabGroup7
        function TabGroup7SelectionChanged(app, event)
            selectedTab = app.TabGroup7.SelectedTab;
            selected_scenario1 = selectedTab.Title;
            switch selected_scenario1
                case 'ENSO'
                   str = ['El Ni' char(0241) 'o (°C)']; % tilde char
                   plot(app.Elnino_Axes,app.data.Year_elnino,app.data.elnino)
                   xlabel(app.Elnino_Axes,'Year')
                   ylabel(app.Elnino_Axes,str)
                   set(app.Elnino_Axes,'FontSize',14)
                case 'AMO'
                   plot(app.AMO_Axes,app.data.Year_AMO_yearly,app.data.AMO_hadsst_yearly)
                   hold(app.AMO_Axes,'on')
                   plot(app.AMO_Axes,app.data.Year_AMO_ersst_yearly,app.data.AMO_ersst_yearly)
                   xlabel(app.AMO_Axes,'Year')
                   ylabel(app.AMO_Axes,'AMO (°C)')
                   legend(app.AMO_Axes,'HadSST dataset','ERSST dataset')
                   set(app.AMO_Axes,'FontSize',14)
                   hold(app.AMO_Axes,'off')
                case 'IPO'
                    plot(app.IPO_Axes,app.data.IPO_ERSST5_mean(:,1),app.data.IPO_ERSST5_mean(:,2))
                    hold(app.IPO_Axes,"on")
                    plot(app.IPO_Axes,app.data.IPO_HADISST_mean(:,1),app.data.IPO_HADISST_mean(:,2))
                    hold(app.IPO_Axes,"on")
                    plot(app.IPO_Axes,app.data.IPO_COBE_mean(:,1),app.data.IPO_COBE_mean(:,2))
                    legend(app.IPO_Axes,'NOAA ERSST V5','HadISST 1.1','COBE')
                    xlabel(app.IPO_Axes,'Year')
                    ylabel(app.IPO_Axes,'IPO Tripole Index (°C)')
                    set(app.IPO_Axes,'FontSize',14)
                    hold(app.IPO_Axes,"off")
                case 'PDO'
                    plot(app.PDO_Axes,app.data.PDO_Mantua_mean(:,1),app.data.PDO_Mantua_mean(:,2))
                    xlabel(app.PDO_Axes,'Year')
                    ylabel(app.PDO_Axes,'PDO Index')
                    set(app.PDO_Axes,'FontSize',14)
                case 'Sunspot numbers'
                    plot(app.sunspots_Axes,app.data.Sunspots_mean(:,1),app.data.Sunspots_mean(:,2))
                    xlabel(app.sunspots_Axes,'Year')
                    ylabel(app.sunspots_Axes,'Sunspot numbers')
                    set(app.sunspots_Axes,'FontSize',14)
                case 'Volcanic activity'
                    app.VEI_table.Data = app.data.VEI;
                    %plot(app.volcanic_Axes,app.data.VEI.Year,app.data.VEI.VEI,'o')
                    bar(app.volcanic_Axes,app.data.VEI.Year,app.data.VEI.VEI)
                    xlabel(app.volcanic_Axes,"Year")
                    ylabel(app.volcanic_Axes,"Volcanic Eruption Index")
                    %app.volcanic_Axes.YTick([1750:50:2000])
                    set(app.volcanic_Axes,'FontSize',14,'XLim',[1750,2000],'XTick',[1750:50:2000])
                case 'Make plots'
%                     if app.ENSOCheckBox.Value==1
%                         plot(app.compare_Axes,app.data.Year_elnino,app.data.elnino)
%                     end
%                     if app.AMOCheckBox.Value == 1
%                         hold(app.compare_Axes,'on')
%                         plot(app.compare_Axes,app.data.Year_AMO_yearly,app.data.AMO_hadsst_yearly)
%                     end
%                     if app.IPOCheckBox.Value == 1
%                         %hold(app.compare_Axes,'on')
%                         plot(app.compare_Axes,app.data.IPO_ERSST5_mean(:,1),app.data.IPO_ERSST5_mean(:,2))
%                     end
%                     if app.PDOCheckBox.Value == 1
%                         hold(app.compare_Axes,'on')
%                         plot(app.compare_Axes,app.data.PDO_Mantua_mean(:,1),app.data.PDO_Mantua_mean(:,2))
%                     end
%                     if app.TemperatureanomaliesCheckBox.Value==1
%                         hold(app.compare_Axes,'on')
%                         plot(app.compare_Axes,app.data.year,app.data.temp,'o')
%                         xlabel(app.compare_Axes,"Year")
%                         set(app.compare_Axes,'FontSize',14)
%                     end
%                     if app.VolcanicactivityCheckBox.Value ==1
%                         hold(app.compare_Axes,'on')
%                         plot(app.compare_Axes,app.data.VEI.Year,app.data.VEI.VEI)
%                     end
                    
            end
                    
        end

        % Button pushed function: PlotvariablesButton
        function PlotvariablesButtonPushed(app, event)
                    hold(app.compare_Axes,'off')
                    
                    elnino=app.data.elnino;
                    AMO=app.data.AMO_hadsst_yearly;
                    IPO=app.data.IPO_HADISST_mean(:,2);
                    PDO = app.data.PDO_Mantua_mean(:,2);
                    temp = app.data.temp;
                    VEI = app.data.VEI.VEI;
                    if app.NormalizeCheckBox.Value==1
                       elnino=(elnino-min(elnino))./(max(elnino)-min(elnino));
                       AMO=(AMO-min(AMO))./(max(AMO)-min(AMO));
                       IPO=(IPO-min(IPO))./(max(IPO)-min(IPO));
                       PDO=(PDO-min(PDO))./(max(PDO)-min(PDO));
                       temp = (temp-min(temp))./(max(temp)-min(temp));
                       VEI = (VEI-min(VEI))./(max(VEI)-min(VEI));
                    end
                    if app.TemperatureanomaliesCheckBox.Value ==1 
                        plot(app.compare_Axes,app.data.year,temp,'DisplayName','Temperature anomalies')
                    end
                    
                    if app.ENSOCheckBox.Value==1
                        hold(app.compare_Axes,'on')
                        plot(app.compare_Axes,app.data.Year_elnino,elnino,'DisplayName','ENSO')
                    end
                    if app.AMOCheckBox.Value == 1
                        hold(app.compare_Axes,'on')
                        plot(app.compare_Axes,app.data.Year_AMO_yearly,AMO,'DisplayName','AMO')
                    end
                    if app.IPOCheckBox.Value == 1
                        hold(app.compare_Axes,'on')
                        plot(app.compare_Axes,app.data.IPO_HADISST_mean(:,1),IPO,'DisplayName','IPO')
                    end
                    if app.PDOCheckBox.Value == 1
                        hold(app.compare_Axes,'on')
                        plot(app.compare_Axes,app.data.PDO_Mantua_mean(:,1),PDO,'DisplayName','PDO')
                    end
                    if app.VolcanicactivityCheckBox.Value == 1
                        hold(app.compare_Axes,'on')
                        plot(app.compare_Axes,app.data.VEI.Year,VEI,'o','DisplayName','VEI')
                    end
                    legend(app.compare_Axes,'-DynamicLegend')
                    hold(app.compare_Axes,'off')
        end

        % Button pushed function: PlotfrequenciesButton
        function PlotfrequenciesButtonPushed(app, event)
            hold(app.compare_Axes,'off')
            
            if app.ENSOCheckBox.Value == 1
                relNums=app.data.elnino;
                fs = 12;
                [pxx,f] = periodogram(relNums,[],[],fs);
            
                plot(app.compare_Axes,1./f,10*log10(pxx))
                ylabel(app.compare_Axes,'Logarithm of Power spectrum density')
                xlabel(app.compare_Axes,'Period (Years)')
            end
            if app.AMOCheckBox.Value == 1
                relNums=app.data.AMO_hadsst_yearly;
                fs = 1;
                [pxx,f] = periodogram(relNums,[],[],fs);
            
                plot(app.compare_Axes,1./f,10*log10(pxx))
                ylabel(app.compare_Axes,'Logarithm of Power spectrum density')
                xlabel(app.compare_Axes,'Period (Years)')
            end
            if app.IPOCheckBox.Value == 1
                relNums=app.data.IPO_HADISST_mean(:,2);
                fs = 1;
                [pxx,f] = periodogram(relNums,[],[],fs);
            
                plot(app.compare_Axes,1./f,10*log10(pxx))
                ylabel(app.compare_Axes,'Logarithm of Power spectrum density')
                xlabel(app.compare_Axes,'Period (Years)')
            end
            if app.PDOCheckBox.Value == 1
                relNums=app.data.PDO_Mantua_mean(:,2);
                fs = 1;
                [pxx,f] = periodogram(relNums,[],[],fs);
            
                plot(app.compare_Axes,1./f,10*log10(pxx))
                ylabel(app.compare_Axes,'Logarithm of Power spectrum density')
                xlabel(app.compare_Axes,'Period (Years)')
            end
            if app.SunspotnumbersCheckBox.Value == 1
                relNums=app.data.Sunspots_mean(:,2);
                fs = 1;
                [pxx,f] = periodogram(relNums,[],[],fs);
            
                plot(app.compare_Axes,1./f,10*log10(pxx))
                ylabel(app.compare_Axes,'Logarithm of Power spectrum density')
                xlabel(app.compare_Axes,'Period (Years)')
            end
            %hold(app.compare_Axes,"on")
            %[pxx,f] = pwelch(relNums,[],[],[],fs);
            %plot(app.compare_Axes,f,10*log10(pxx))
            
            %cwt(app.data.elnino,years(1/12));
           
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            swimmingpool_vol = app.LengthmEditField.Value*app.WidthmEditField.Value*app.DepthmEditField.Value;
            
            CO2_footprint_g = app.COfootprintEditField.Value * 1000 * 1000; % g
            CO2_Mol_mass = 44; %g/mol
            CO2_n = CO2_footprint_g/CO2_Mol_mass; % mol
            CO2_volume = (CO2_n * 8.314 * (app.TemperatureCSlider.Value+273.15))/(app.PressurehPaSlider.Value * 100); % m3
            
            app.swimmingpoolsTextArea.Value = num2str(CO2_volume/swimmingpool_vol); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CC_estimator_GUI';
            app.file = uimenu(app.figure1);
            app.file.Text = 'File';
            app.save = uimenu(app.file);
            app.save.Text = 'Save';
            app.edit = uimenu(app.figure1);
            app.edit.Text = 'Edit';
            app.new_model = uimenu(app.edit);
            app.new_model.Text = 'Train new model';
            app.TabGroup = uitabgroup(app.figure1);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FutureCOTab = uitab(app.TabGroup);
            app.FutureCOTab.Title = 'Future CO₂';
            app.TabGroup2 = uitabgroup(app.FutureCOTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.COconcentrationsTab = uitab(app.TabGroup2);
            app.COconcentrationsTab.Title = 'CO₂ concentrations';
            app.axes2 = uiaxes(app.COconcentrationsTab);
            xlabel(app.axes2, 'X')
            ylabel(app.axes2, 'Y')
            app.rcp_scenarios_2 = uibuttongroup(app.COconcentrationsTab);
            app.rcp_scenarios_2.SelectionChangedFcn = createCallbackFcn(app, @rcp_scenarios_2SelectionChanged, true);
            app.rcp_scenarios_2.Title = 'Scenarios';
            app.radiobutton6_2 = uiradiobutton(app.rcp_scenarios_2);
            app.radiobutton6_2.Text = 'RCP2.6';
            app.radiobutton6_2.Value = true;
            app.radiobutton7_2 = uiradiobutton(app.rcp_scenarios_2);
            app.radiobutton7_2.Text = 'RCP4.5';
            app.radiobutton8_2 = uiradiobutton(app.rcp_scenarios_2);
            app.radiobutton8_2.Text = 'RCP6.0';
            app.radiobutton9_2 = uiradiobutton(app.rcp_scenarios_2);
            app.radiobutton9_2.Text = 'RCP8.5';
            app.radiobutton10_2 = uiradiobutton(app.rcp_scenarios_2);
            app.radiobutton10_2.Text = 'All RCP';
            app.TextArea_12 = uitextarea(app.COconcentrationsTab);
            app.TextArea_12.Value = {'Hint: In the RCP2.6 scenario a technology development is assumed that allows to extract CO2 out of the atmosphere which lead to overall negative CO2 emissions.'};
            app.COemissionsTab = uitab(app.TabGroup2);
            app.COemissionsTab.Title = 'CO₂ emissions';
            app.axes3 = uiaxes(app.COemissionsTab);
            xlabel(app.axes3, 'X')
            ylabel(app.axes3, 'Y')
            app.rcp_scenarios_3 = uibuttongroup(app.COemissionsTab);
            app.rcp_scenarios_3.SelectionChangedFcn = createCallbackFcn(app, @rcp_scenarios_3SelectionChanged, true);
            app.rcp_scenarios_3.Title = 'Scenarios';
            app.radiobutton6_3 = uiradiobutton(app.rcp_scenarios_3);
            app.radiobutton6_3.Text = 'RCP2.6';
            app.radiobutton6_3.Value = true;
            app.radiobutton7_3 = uiradiobutton(app.rcp_scenarios_3);
            app.radiobutton7_3.Text = 'RCP4.5';
            app.radiobutton8_3 = uiradiobutton(app.rcp_scenarios_3);
            app.radiobutton8_3.Text = 'RCP6.0';
            app.radiobutton9_3 = uiradiobutton(app.rcp_scenarios_3);
            app.radiobutton9_3.Text = 'RCP8.5';
            app.radiobutton10_3 = uiradiobutton(app.rcp_scenarios_3);
            app.radiobutton10_3.Text = 'All RCP';
            app.TextArea_2 = uitextarea(app.COemissionsTab);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Value = {'Hint: In the RCP2.6 scenario a technology development is assumed that allow to extract CO2 out of the atmosphere which lead to overall negative CO2 emissions.'};
            app.CObudgetTab = uitab(app.TabGroup2);
            app.CObudgetTab.Title = 'CO₂ budget';
            app.CountryDropDownLabel = uilabel(app.CObudgetTab);
            app.CountryDropDownLabel.Text = 'Country';
            app.CountryDropDown = uidropdown(app.CObudgetTab);
            app.CountryDropDown.Items = {'Afghanistan', 'Albania', 'Algeria', 'Angola', 'Anguilla', 'Antigua and Barbuda', 'Argentina', 'Armenia', 'Aruba', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bermuda', 'Bhutan', 'Bolivia', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'British Virgin Islands', 'Brunei', 'Bulgaria', 'Burkina Faso', 'Burundi', 'Cambodia', 'Cameroon', 'Canada', 'Cape Verde', 'Cayman Islands', 'Central African Republic', 'Chad', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo', 'Cook Islands', 'Costa Rica', 'Côte d’Ivoire', 'Croatia', 'Cuba', 'Curaçao', 'Cyprus', 'Czechia', 'Democratic Republic of the Congo', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'Ecuador', 'Egypt', 'El Salvador', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Eswatini', 'Ethiopia', 'EU28', 'Falkland Islands', 'Faroes', 'Fiji', 'Finland', 'France', 'French Guiana', 'French Polynesia', 'Gabon', 'Gambia', 'Georgia', 'Germany', 'Ghana', 'Gibraltar', 'Greece', 'Greenland', 'Grenada', 'Guadeloupe', 'Guatemala', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'Honduras', 'Hong Kong', 'Hungary', 'Iceland', 'India', 'Indonesia', 'International Aviation', 'International Shipping', 'Iran', 'Iraq', 'Ireland', 'Israel and Palestine', 'Italy', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kiribati', 'Kuwait', 'Kyrgyzstan', 'Laos', 'Latvia', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Lithuania', 'Luxembourg', 'Macao', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Martinique', 'Mauritania', 'Mauritius', 'Mexico', 'Moldova', 'Mongolia', 'Morocco', 'Mozambique', 'Myanmar/Burma', 'Namibia', 'Nepal', 'Netherlands', 'New Caledonia', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Oman', 'Pakistan', 'Palau', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Réunion', 'Romania', 'Russia', 'Rwanda', 'Saint Helena', ' Ascension and Tristan da Cunha', 'Saint Kitts and Nevis', 'Saint Lucia', 'Saint Pierre and Miquelon', 'Saint Vincent and the Grenadines', 'Samoa', 'São Tomé and Príncipe', 'Saudi Arabia', 'Senegal', 'Serbia and Montenegro', 'Seychelles', 'Sierra Leone', 'Singapore', 'Slovakia', 'Slovenia', 'Solomon Islands', 'Somalia', 'South Africa', 'South Korea', 'Spain and Andorra', 'Sri Lanka', 'Sudan and South Sudan', 'Suriname', 'Sweden', 'Switzerland and Liechtenstein', 'Syria', 'Taiwan', 'Tajikistan', 'Tanzania', 'Thailand', 'Timor-Leste', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Turkmenistan', 'Turks and Caicos Islands', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'United States', 'Uruguay', 'Uzbekistan', 'Vanuatu', 'Venezuela', 'Vietnam', 'Western Sahara', 'Yemen', 'Zambia', 'Zimbabwe'};
            app.CountryDropDown.Value = 'China';
            app.CO2budget_Axes = uiaxes(app.CObudgetTab);
            xlabel(app.CO2budget_Axes, 'X')
            ylabel(app.CO2budget_Axes, 'Y')
            app.BudgetGtonCO2Label = uilabel(app.CObudgetTab);
            app.BudgetGtonCO2Label.Text = {'Budget '; '(Gton CO2)'};
            app.BudgetGtonCO2TextArea = uitextarea(app.CObudgetTab);
            app.Yearsto0emissionsfrom2018Label = uilabel(app.CObudgetTab);
            app.Yearsto0emissionsfrom2018Label.Text = {'Years to 0 '; 'emissions '; 'from 2018'};
            app.Yearstoemissions = uitextarea(app.CObudgetTab);
            app.ShowbudgetButton = uibutton(app.CObudgetTab, 'push');
            app.ShowbudgetButton.ButtonPushedFcn = createCallbackFcn(app, @ShowbudgetButtonPushed, true);
            app.ShowbudgetButton.Text = 'Show budget';
            app.TextArea_16 = uitextarea(app.CObudgetTab);
            app.TextArea_16.Value = {'This tab shows pathways to 0 emissions based on global CO2 budgets that will likely lead to lower than 1.5/2 °C. The pathways are constructed on a per-capita basis. So the population size of each country and the emissions in 2018 (as the starting point) are the key factors that determine how fast a country has to get to 0 emissions. Go and find the hidden champions!'; 'More info in the Ebook.'};
            app.keepthecurrentplots = uicheckbox(app.CObudgetTab);
            app.keepthecurrentplots.Text = 'keep the current plot(s)';
            app.GlobalbudgetGtonCO2Label = uilabel(app.CObudgetTab);
            app.GlobalbudgetGtonCO2Label.Text = {'Global budget'; ' (Gton CO2)'};
            app.Globalbudget = uieditfield(app.CObudgetTab, 'numeric');
            app.Globalbudget.Tooltip = {'Global budget can be chosen from IPCC SR15 Table 2.2'};
            app.Globalbudget.Value = 580;
            app.COcalculatorTab = uitab(app.TabGroup2);
            app.COcalculatorTab.Title = 'CO₂ calculator';
            app.TextArea_36 = uitextarea(app.COcalculatorTab);
            app.TextArea_36.Value = {'Calculate how many olympic swimming pools can be filled with a given CO₂ footprint (tons CO₂ per year).'; 'The volume is calculated for typical conditions at the surface (1013 hPa and 15 °C), but you can change it as you like.'; 'To calculate your CO₂ footprint you can use many free online calculators. In Germany, you can use the online calculator provided by the Umweltbundesamt. '};
            app.COfootprinttonsperyearEditFieldLabel = uilabel(app.COcalculatorTab);
            app.COfootprinttonsperyearEditFieldLabel.Text = 'CO₂ footprint (tons per year)';
            app.COfootprintEditField = uieditfield(app.COcalculatorTab, 'numeric');
            app.COfootprintEditField.Value = 10;
            app.LengthmEditFieldLabel = uilabel(app.COcalculatorTab);
            app.LengthmEditFieldLabel.Text = 'Length (m)';
            app.LengthmEditField = uieditfield(app.COcalculatorTab, 'numeric');
            app.LengthmEditField.Value = 50;
            app.WidthmEditFieldLabel = uilabel(app.COcalculatorTab);
            app.WidthmEditFieldLabel.Text = 'Width (m)';
            app.WidthmEditField = uieditfield(app.COcalculatorTab, 'numeric');
            app.WidthmEditField.Value = 25;
            app.DepthmEditFieldLabel = uilabel(app.COcalculatorTab);
            app.DepthmEditFieldLabel.Text = 'Depth (m)';
            app.DepthmEditField = uieditfield(app.COcalculatorTab, 'numeric');
            app.DepthmEditField.Value = 2;
            app.TextArea_38 = uitextarea(app.COcalculatorTab);
            app.TextArea_38.Value = {'For the calculation you just need to know 2 things now:'; ''; '(1) the molecular weight of CO₂ = 44 g/mol'; ''; '(2) the volume of gas per mol = 22.4 l'};
            app.NumberofolympicswimmingpoolsfilledwithCOLabel = uilabel(app.COcalculatorTab);
            app.NumberofolympicswimmingpoolsfilledwithCOLabel.Text = {'Number of olympic swimming pools'; ' filled with CO₂:'};
            app.swimmingpoolsTextArea = uitextarea(app.COcalculatorTab);
            app.Image = uiimage(app.COcalculatorTab);
            app.Image.ImageSource = 'pool.png';
            app.SwimmingpoolsizeLabel = uilabel(app.COcalculatorTab);
            app.SwimmingpoolsizeLabel.Text = 'Swimming pool size:';
            app.CalculateButton = uibutton(app.COcalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PressurehPaSliderLabel = uilabel(app.COcalculatorTab);
            app.PressurehPaSliderLabel.Text = 'Pressure (hPa)';
            app.PressurehPaSlider = uislider(app.COcalculatorTab);
            app.PressurehPaSlider.Limits = [200 1100];
            app.PressurehPaSlider.Value = 1013;
            app.TemperatureCSliderLabel = uilabel(app.COcalculatorTab);
            app.TemperatureCSliderLabel.Text = 'Temperature (°C)';
            app.TemperatureCSlider = uislider(app.COcalculatorTab);
            app.TemperatureCSlider.Limits = [-40 60];
            app.ChangeheightandpressureasyoulikethevolumedependsonbothLabel = uilabel(app.COcalculatorTab);
            app.ChangeheightandpressureasyoulikethevolumedependsonbothLabel.Text = 'Change height and pressure as you like (the volume depends on both):';
            app.HistoricaldataTab = uitab(app.TabGroup);
            app.HistoricaldataTab.Title = 'Historical data';
            app.TabGroup7 = uitabgroup(app.HistoricaldataTab);
            app.TabGroup7.SelectionChangedFcn = createCallbackFcn(app, @TabGroup7SelectionChanged, true);
            app.ENSOTab = uitab(app.TabGroup7);
            app.ENSOTab.Title = 'ENSO';
            app.GridLayout = uigridlayout(app.ENSOTab);
            app.Elnino_Axes = uiaxes(app.GridLayout);
            title(app.Elnino_Axes, '')
            xlabel(app.Elnino_Axes, 'X')
            ylabel(app.Elnino_Axes, 'Y')
            app.TextArea_29 = uitextarea(app.GridLayout);
            app.TextArea_29.Value = {'ENSO stands for "El Niño -  Southern Oscillation. El Niño is a phenomenon of the southern Pacific that is related to unusual convective conditions and higher than normal ocean surface temperatures close to the Southamerican coast line. Fishermen have given this phenomenon its name, because it usually occurs closely before Christmas and El Niño is a spanish word that stands for Christ Child.'; '"Southern Oscillation" relates to indices that describe the variation of meteorological and oceanic conditions in the area of the southern Pacific. EL Niño 3.4 is one of the most used indices and describes ocean surface temperature anomalies whereby the number 3.4 refers to the geographic area in which these anomalies are averaged.'; ''};
            app.AMOTab_2 = uitab(app.TabGroup7);
            app.AMOTab_2.Title = 'AMO';
            app.GridLayout2 = uigridlayout(app.AMOTab_2);
            app.AMO_Axes = uiaxes(app.GridLayout2);
            title(app.AMO_Axes, '')
            xlabel(app.AMO_Axes, 'X')
            ylabel(app.AMO_Axes, 'Y')
            app.TextArea_30 = uitextarea(app.GridLayout2);
            app.TextArea_30.Value = {'The Atlantic Multidecadal Oscillation (AMO) index is one example of internal variability of the climate system. The AMO index is defined here as the sea surface temperature (SST) averaged over 0°-60°N, 0°-80°W minus SST averaged over 60°S-60°N.'; 'The data stems from two sources. The HadSST data is produced by the Met Office Hadley Center, which is the research center of the national meteorological service of the UK. The ERSST dataset is provided by the National Oceanic and Atmospheric Association of the United States of America.'; 'As you can see, the indices from the different data sources agree quite well.'};
            app.IPOTab = uitab(app.TabGroup7);
            app.IPOTab.Title = 'IPO';
            app.GridLayout2_2 = uigridlayout(app.IPOTab);
            app.IPO_Axes = uiaxes(app.GridLayout2_2);
            title(app.IPO_Axes, '')
            xlabel(app.IPO_Axes, 'X')
            ylabel(app.IPO_Axes, 'Y')
            app.TextArea_31 = uitextarea(app.GridLayout2_2);
            app.TextArea_31.Value = {'The IPO tripole index is calculated as the difference between the average sea surface temperature anomalies of the central equatorial Pacific and two other regions of the Pacific (northwest and southwest Pacific).'; 'The IPO Index is presented for 3 different datasets: ERSST from NOAA (USA), HadISST from Met Office Hadley Centre (UK) and COBE from Japan Meteorological Agency. '; 'As you can see there is a high agreement between the data sources after 1955. Before that a higher uncertainty is visible. This could be due to different correction methods of older raw data sources.'; ''; 'Data source:'; 'Henley, B.J., Gergis, J., Karoly, D.J., Power, S.B., Kennedy, J., & Folland, C.K. (2015). A Tripole Index for the Interdecadal Pacific Oscillation. Climate Dynamics, 45(11-12), 3077-3090.http://dx.doi.org/10.1007/s00382-015-2525-1 . Accessed on 11 11 2019 at "https://www.esrl.noaa.gov/psd/data/timeseries/IPOTPI".'; ''; ''; ''};
            app.PDOTab = uitab(app.TabGroup7);
            app.PDOTab.Title = 'PDO';
            app.GridLayout2_3 = uigridlayout(app.PDOTab);
            app.PDO_Axes = uiaxes(app.GridLayout2_3);
            title(app.PDO_Axes, '')
            xlabel(app.PDO_Axes, 'X')
            ylabel(app.PDO_Axes, 'Y')
            app.TextArea_32 = uitextarea(app.GridLayout2_3);
            app.SunspotnumbersTab = uitab(app.TabGroup7);
            app.SunspotnumbersTab.Title = 'Sunspot numbers';
            app.GridLayout2_4 = uigridlayout(app.SunspotnumbersTab);
            app.sunspots_Axes = uiaxes(app.GridLayout2_4);
            title(app.sunspots_Axes, '')
            xlabel(app.sunspots_Axes, 'X')
            ylabel(app.sunspots_Axes, 'Y')
            app.TextArea_33 = uitextarea(app.GridLayout2_4);
            app.VolcanicactivityTab = uitab(app.TabGroup7);
            app.VolcanicactivityTab.Title = 'Volcanic activity';
            app.GridLayout2_5 = uigridlayout(app.VolcanicactivityTab);
            app.volcanic_Axes = uiaxes(app.GridLayout2_5);
            title(app.volcanic_Axes, '')
            xlabel(app.volcanic_Axes, 'X')
            ylabel(app.volcanic_Axes, 'Y')
            app.TextArea_34 = uitextarea(app.GridLayout2_5);
            app.VEI_table = uitable(app.GridLayout2_5);
            app.VEI_table.ColumnName = {'Year'; 'VEI'; 'Volcano'};
            app.VEI_table.RowName = {};
            app.TextArea_35 = uitextarea(app.GridLayout2_5);
            app.TextArea_35.Value = {'The table above shows a selection of large volcanic eruptions after Robock (2000). The strength of the eruptions is quantified by the Volcanic Explosivity Index (VEI). This index is logarithmic and depends on the volume of volcanic material thrown out, the column height and the duration of the eruption.'};
            app.MakeplotsTab = uitab(app.TabGroup7);
            app.MakeplotsTab.Title = 'Make plots';
            app.compare_Axes = uiaxes(app.MakeplotsTab);
            title(app.compare_Axes, '')
            xlabel(app.compare_Axes, 'X')
            ylabel(app.compare_Axes, 'Y')
            app.PlotvariablesButton = uibutton(app.MakeplotsTab, 'push');
            app.PlotvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotvariablesButtonPushed, true);
            app.PlotvariablesButton.Text = 'Plot variables';
            app.PlotvariablesagainsttimeLabel = uilabel(app.MakeplotsTab);
            app.PlotvariablesagainsttimeLabel.Text = 'Plot variables against time:';
            app.When2variablesareselectedyoucanplotthemagainsteachotherLabel = uilabel(app.MakeplotsTab);
            app.When2variablesareselectedyoucanplotthemagainsteachotherLabel.Text = {'When 2 variables are selected '; 'you can plot them against each'; ' other:'};
            app.MakescatterplotButton = uibutton(app.MakeplotsTab, 'push');
            app.MakescatterplotButton.Text = 'Make scatterplot';
            app.VariablestoplotPanel = uipanel(app.MakeplotsTab);
            app.VariablestoplotPanel.Title = 'Variables to plot';
            app.ENSOCheckBox = uicheckbox(app.VariablestoplotPanel);
            app.ENSOCheckBox.Text = 'ENSO';
            app.AMOCheckBox = uicheckbox(app.VariablestoplotPanel);
            app.AMOCheckBox.Text = 'AMO';
            app.IPOCheckBox = uicheckbox(app.VariablestoplotPanel);
            app.IPOCheckBox.Text = 'IPO';
            app.PDOCheckBox = uicheckbox(app.VariablestoplotPanel);
            app.PDOCheckBox.Text = 'PDO';
            app.SunspotnumbersCheckBox = uicheckbox(app.VariablestoplotPanel);
            app.SunspotnumbersCheckBox.Text = 'Sunspot numbers';
            app.VolcanicactivityCheckBox = uicheckbox(app.VariablestoplotPanel);
            app.VolcanicactivityCheckBox.Text = 'Volcanic activity';
            app.TemperatureanomaliesCheckBox = uicheckbox(app.VariablestoplotPanel);
            app.TemperatureanomaliesCheckBox.Text = 'Temperature anomalies';
            app.NormalizeCheckBox = uicheckbox(app.MakeplotsTab);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.Studyperiodsofdifferentindizesworksonlyfor1variableatatimeLabel = uilabel(app.MakeplotsTab);
            app.Studyperiodsofdifferentindizesworksonlyfor1variableatatimeLabel.Tooltip = {'this shows the frequencies of the climate index'};
            app.Studyperiodsofdifferentindizesworksonlyfor1variableatatimeLabel.Text = {'Study periods of different indizes '; '(works only for 1 variable at a '; 'time):'};
            app.PlotfrequenciesButton = uibutton(app.MakeplotsTab, 'push');
            app.PlotfrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfrequenciesButtonPushed, true);
            app.PlotfrequenciesButton.Text = 'Plot frequencies';
            app.StudyhowperiodschangeovertimeLabel = uilabel(app.MakeplotsTab);
            app.StudyhowperiodschangeovertimeLabel.Text = {'Study how periods change over'; 'time:'};
            app.MakewaveletplotButton = uibutton(app.MakeplotsTab, 'push');
            app.MakewaveletplotButton.Text = 'Make wavelet plot';
            app.StatisticalmodelTab = uitab(app.TabGroup);
            app.StatisticalmodelTab.Title = 'Statistical model';
            app.TabGroup5 = uitabgroup(app.StatisticalmodelTab);
            app.TrainingdataTab = uitab(app.TabGroup5);
            app.TrainingdataTab.Title = 'Training data';
            app.TabGroup3 = uitabgroup(app.TrainingdataTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.TemperatureTab = uitab(app.TabGroup3);
            app.TemperatureTab.Title = 'Temperature';
            app.axes4 = uiaxes(app.TemperatureTab);
            xlabel(app.axes4, 'X')
            ylabel(app.axes4, 'Y')
            app.AMOTab = uitab(app.TabGroup3);
            app.AMOTab.Title = 'AMO';
            app.axes4_2 = uiaxes(app.AMOTab);
            xlabel(app.axes4_2, 'X')
            ylabel(app.axes4_2, 'Y')
            app.SunspotnumberTab = uitab(app.TabGroup3);
            app.SunspotnumberTab.Title = 'Sunspot number';
            app.axes4_3 = uiaxes(app.SunspotnumberTab);
            xlabel(app.axes4_3, 'X')
            ylabel(app.axes4_3, 'Y')
            app.COTab = uitab(app.TabGroup3);
            app.COTab.Title = 'CO₂';
            app.axes4_4 = uiaxes(app.COTab);
            xlabel(app.axes4_4, 'X')
            ylabel(app.axes4_4, 'Y')
            app.ModelevaluationTab = uitab(app.TabGroup5);
            app.ModelevaluationTab.Title = 'Model evaluation';
            app.TabGroup4 = uitabgroup(app.ModelevaluationTab);
            app.HistoricalDataTab = uitab(app.TabGroup4);
            app.HistoricalDataTab.Title = 'Historical Data';
            app.Scatterplot_Button = uibutton(app.HistoricalDataTab, 'push');
            app.Scatterplot_Button.ButtonPushedFcn = createCallbackFcn(app, @Scatterplot_ButtonPushed, true);
            app.Scatterplot_Button.Text = 'Plot model vs data';
            app.residuals = uiaxes(app.HistoricalDataTab);
            xlabel(app.residuals, 'X')
            ylabel(app.residuals, 'Y')
            app.residuals_Button = uibutton(app.HistoricalDataTab, 'push');
            app.residuals_Button.ButtonPushedFcn = createCallbackFcn(app, @residuals_ButtonPushed, true);
            app.residuals_Button.Text = 'Plot residuals';
            app.scatterplot = uiaxes(app.HistoricalDataTab);
            xlabel(app.scatterplot, 'X')
            ylabel(app.scatterplot, 'Y')
            app.TextArea_20 = uitextarea(app.HistoricalDataTab);
            app.TextArea_20.Value = {'R² =  0.90'; 'adjusted R²  = 0.90'; ''; 'RMSE =  0.099 °C'; ''; 'Average RMSE of 10-fold crossvalidation = 0.101 °C'; ''; 'Mean of the residuals = -2.6228e-05'; ''; ''};
            app.ErrorestimatesLabel = uilabel(app.HistoricalDataTab);
            app.ErrorestimatesLabel.Text = 'Error estimates:';
            app.IPCCmodelsTab = uitab(app.TabGroup4);
            app.IPCCmodelsTab.Title = 'IPCC models';
            app.cmip5 = uiaxes(app.IPCCmodelsTab);
            xlabel(app.cmip5, 'X')
            ylabel(app.cmip5, 'Y')
            app.rcp_scenarios_4 = uibuttongroup(app.IPCCmodelsTab);
            app.rcp_scenarios_4.SelectionChangedFcn = createCallbackFcn(app, @rcp_scenarios_4SelectionChanged, true);
            app.rcp_scenarios_4.Title = 'Scenarios';
            app.radiobutton7_6 = uiradiobutton(app.rcp_scenarios_4);
            app.radiobutton7_6.Text = 'RCP4.5';
            app.radiobutton7_6.Value = true;
            app.radiobutton8_6 = uiradiobutton(app.rcp_scenarios_4);
            app.radiobutton8_6.Text = 'RCP6.0';
            app.radiobutton9_6 = uiradiobutton(app.rcp_scenarios_4);
            app.radiobutton9_6.Text = 'RCP8.5';
            app.TextArea_21 = uitextarea(app.IPCCmodelsTab);
            app.TextArea_21.Value = {'Thick black line= CC estimator'; ''; 'Colored thin lines= model results from CMIP5'; ''; 'Black circles = measured temperature anomalies'};
            app.TextArea_22 = uitextarea(app.IPCCmodelsTab);
            app.TextArea_22.Value = {'Hint: Zoom in to the year 1991 and you''ll see the effect of an individual volcanic eruption of Mt. Pinatubo. If you look closely you can spot another one in the time series. More info in the Ebook.'};
            app.ModifyscenariosTab_2 = uitab(app.TabGroup5);
            app.ModifyscenariosTab_2.Title = 'Modify scenarios';
            app.TextArea_17 = uitextarea(app.ModifyscenariosTab_2);
            app.TextArea_17.Value = {'You can import and explore the effects of your own custom RCP scenario here. For that, prepare a csv file (comma separated) with CO2 concentrations (ppm) starting in 1980 and ending with 2100 so that you have 1 column and 121 rows. You can do the same thing with AMO and sunspot numbers!'};
            app.RunscenariosPanel = uipanel(app.ModifyscenariosTab_2);
            app.RunscenariosPanel.Title = 'Run scenarios';
            app.RuncustommodelButton = uibutton(app.RunscenariosPanel, 'push');
            app.RuncustommodelButton.ButtonPushedFcn = createCallbackFcn(app, @RuncustommodelButtonPushed, true);
            app.RuncustommodelButton.Text = 'Run custom model';
            app.ComparewithRCPscenariosButton = uibutton(app.RunscenariosPanel, 'push');
            app.ComparewithRCPscenariosButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithRCPscenariosButtonPushed, true);
            app.ComparewithRCPscenariosButton.Text = 'Compare with RCP scenarios';
            app.PreparescenariosPanel = uipanel(app.ModifyscenariosTab_2);
            app.PreparescenariosPanel.Title = 'Prepare scenarios';
            app.ImportRCPButton = uibutton(app.PreparescenariosPanel, 'push');
            app.ImportRCPButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRCPButtonPushed, true);
            app.ImportRCPButton.Text = 'Import RCP';
            app.ImportAMOButton = uibutton(app.PreparescenariosPanel, 'push');
            app.ImportAMOButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAMOButtonPushed, true);
            app.ImportAMOButton.Text = 'Import AMO';
            app.ImportsunspotnumbersButton = uibutton(app.PreparescenariosPanel, 'push');
            app.ImportsunspotnumbersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsunspotnumbersButtonPushed, true);
            app.ImportsunspotnumbersButton.Text = 'Import sunspot numbers';
            app.PlotRCPButton = uibutton(app.PreparescenariosPanel, 'push');
            app.PlotRCPButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRCPButtonPushed, true);
            app.PlotRCPButton.Text = 'Plot RCP';
            app.PlotAMOButton = uibutton(app.PreparescenariosPanel, 'push');
            app.PlotAMOButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAMOButtonPushed, true);
            app.PlotAMOButton.Text = 'Plot AMO';
            app.PlotsunspotnumbersButton = uibutton(app.PreparescenariosPanel, 'push');
            app.PlotsunspotnumbersButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsunspotnumbersButtonPushed, true);
            app.PlotsunspotnumbersButton.Text = 'Plot sunspot numbers';
            app.TextArea_18 = uitextarea(app.ModifyscenariosTab_2);
            app.TextArea_18.Value = {'Hint 1: If you only change AMO and/or Sunspot numbers, the model will run with the RCP4.5 scenario.'; ''; 'Hint 2: Since future sunspot numbers (longterm) are not predictable you can also look at historical maxima/minima and use these to check for the potential impact that solar activity might have in future.'};
            app.modifiedmodel = uiaxes(app.ModifyscenariosTab_2);
            xlabel(app.modifiedmodel, 'X')
            ylabel(app.modifiedmodel, 'Y')
            app.ModifymodelTab_2 = uitab(app.TabGroup5);
            app.ModifymodelTab_2.Title = 'Modify model';
            app.TextArea_23 = uitextarea(app.ModifymodelTab_2);
            app.TextArea_23.Value = {'You can check and download all sorts of potential input variables on the KNMI Climate Explorer website. Here is the link: https://climexp.knmi.nl/selectindex.cgi'; ''; 'Try it out! Download the variables you are interested in, retrain the model here, check which variables are significant and how it performs against measured temperature anomalies. Eventually you can even improve the CC estimator :). Besides that, you will definitely deepen your knowledge about our climate system!'};
            app.InputdataPanel = uipanel(app.ModifymodelTab_2);
            app.InputdataPanel.Title = 'Input data';
            app.ImportinputdataButton = uibutton(app.InputdataPanel, 'push');
            app.ImportinputdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportinputdataButtonPushed, true);
            app.ImportinputdataButton.Text = 'Import input data';
            app.TextArea_25 = uitextarea(app.InputdataPanel);
            app.TextArea_25.Value = {'Prepare your input data as csv (comma separated!) with 1 header row. That means the data should start at row 2. Yearly averages are expected and the years needs to be in the 1st column. All input variables should begin and end with the same year.'};
            app.modmodel = uiaxes(app.ModifymodelTab_2);
            title(app.modmodel, 'Title')
            xlabel(app.modmodel, 'X')
            ylabel(app.modmodel, 'Y')
            app.TrainmodelPanel = uipanel(app.ModifymodelTab_2);
            app.TrainmodelPanel.Title = 'Train model';
            app.TrainmodelButton = uibutton(app.TrainmodelPanel, 'push');
            app.TrainmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainmodelButtonPushed, true);
            app.TrainmodelButton.Text = 'Train model';
            app.TextArea_26 = uitextarea(app.TrainmodelPanel);
            app.TextArea_26.Value = {'Here, we use stepwise regression to determine which variables are significant and define the final equation.'};
            app.Lamp = uilamp(app.TrainmodelPanel);
            app.Lamp.Enable = 'off';
            app.TestmodelPanel = uipanel(app.ModifymodelTab_2);
            app.TestmodelPanel.Title = 'Test model';
            app.TextArea_27 = uitextarea(app.TestmodelPanel);
            app.TextArea_27.Value = {'Plot your new model against measured temperatures and see how it performs! '};
            app.TestmodelButton = uibutton(app.TestmodelPanel, 'push');
            app.TestmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TestmodelButtonPushed, true);
            app.TestmodelButton.Text = 'Test model';
            app.TextArea_24 = uitextarea(app.ModifymodelTab_2);
            app.TextArea_24.Value = {'Hint for the right plot: In a perfect model (and with perfect data) the points are all on a diagonal line with slope = 1 and the y-intercept = 0.'};
            app.modmodel_2 = uiaxes(app.ModifymodelTab_2);
            xlabel(app.modmodel_2, 'X')
            ylabel(app.modmodel_2, 'Y')
            app.InformationTab_2 = uitab(app.TabGroup5);
            app.InformationTab_2.Title = 'Information';
            app.info_text = uilabel(app.InformationTab_2);
            app.info_text.Text = {'The model presented here is a simple statistical model that was trained with stepwise regression on historical data of global,'; 'mean temperature anomalies. The input data were CO₂ concentrations, the atlantic multidecadal oscillation (AMO), sunspot numbers'; 'and historical data on surface solar radiation. Sunspot numbers are used as a proxy for solar activity and surface solar radiation'; 'to study the potential influence of solar dimming and brightening.'; ''; 'The model equation determined by stepwise regression is:'; ''; 'Temp_anomaly = 3.4159 + 0.0099996 * CO2 [ppm] + 0.0019103 * Sunspot_number + 0.41685 * AMO'; ''; 'The data that was used to train the statistical model is the following:'; ''; 'The historical global yearly CO₂ data used can be downloaded here:'; 'https://www.co2.earth/historical-co2-datasets'; ''; 'The CO₂ data from RCP scenarios and more information about RCP scenarios are available here:'; 'http://www.pik-potsdam.de/~mmalte/rcps/'; ''; 'The global mean temperature anomalies (1881-2010 used here) can be downloaded here:'; 'http://berkeleyearth.org/data/'; ''; 'The Atlantic Multidecadal Oscillation (AMO) Index was downloaded here:'; 'https://www.esrl.noaa.gov/psd/data/timeseries/AMO/'; ''; 'Sunspot numbers were downloaded here:'; 'http://www.sidc.be/silso/datafiles'; ''; 'Surface solar radiation was extracted from Fig 3a from following publication:'; 'Sanchez-Lorenzo A. & Wild M (2012). Decadal variations in estimated surface solar radiation over Switzerland since'; ' the late 19th century. Atmospheric Chemistry and Physics 12(18) 8635-8644.'; ''; 'More information is provided in the Ebook that comes bundled with the app.'; ''; 'Acknowledgments: This work was inspired by school kids around the world who fight for their future (Friday4Future). '};
            app.ExportInputdataButton = uibutton(app.InformationTab_2, 'push');
            app.ExportInputdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportInputdataButtonPushed, true);
            app.ExportInputdataButton.Text = 'Export Input data';
            app.ExportRCPdataButton = uibutton(app.InformationTab_2, 'push');
            app.ExportRCPdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRCPdataButtonPushed, true);
            app.ExportRCPdataButton.Text = 'Export RCP data';
            app.EnergybalancemodelsTab = uitab(app.TabGroup);
            app.EnergybalancemodelsTab.Title = 'Energy balance models';
            app.TabGroup6 = uitabgroup(app.EnergybalancemodelsTab);
            app.SimpleEBMITab = uitab(app.TabGroup6);
            app.SimpleEBMITab.Title = 'Simple EBM I';
            app.EBM = uiaxes(app.SimpleEBMITab);
            app.TextArea_19 = uitextarea(app.SimpleEBMITab);
            app.TextArea_19.Value = {'Here a simple global energy balance model (EBM) from the book "Das Klimasystem und seine Modellierung" by von Storch, Güss and Heimann is presented. You can find more about it in the Ebook.'; ''; 'You can see that this system is stable (in the default case), which means that it comes back to the same temperature after perturbations. You can simulate perturbations by choosing different start temperatures.'; 'With the default values the result equals approximately the real mean global surface temperature of 288 K.'; 'Study the effect of the different parameters. What happens if the ocean heat capacity is increased? Also, switch on the Albedo feedback and/or stochastic fluctuations and change the simulation years to 1000 and 100 000 years.'};
            app.RunEBMmodelButton = uibutton(app.SimpleEBMITab, 'push');
            app.RunEBMmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunEBMmodelButtonPushed, true);
            app.RunEBMmodelButton.Text = 'Run EBM model';
            app.ResetButton = uibutton(app.SimpleEBMITab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AlbedofeedbackButton = uibutton(app.SimpleEBMITab, 'state');
            app.AlbedofeedbackButton.Text = 'Albedo feedback';
            app.StochasticfluctuationsButton = uibutton(app.SimpleEBMITab, 'state');
            app.StochasticfluctuationsButton.Text = 'Stochastic fluctuations';
            app.OceanheatcapacityJm2K1SliderLabel = uilabel(app.SimpleEBMITab);
            app.OceanheatcapacityJm2K1SliderLabel.Text = 'Ocean heat capacity (J m-2 K-1)';
            app.Oceanheatcapacity = uislider(app.SimpleEBMITab);
            app.Oceanheatcapacity.Limits = [0 1000000000];
            app.Oceanheatcapacity.Value = 200000000;
            app.StarttemperatureCEditFieldLabel = uilabel(app.SimpleEBMITab);
            app.StarttemperatureCEditFieldLabel.Text = 'Start temperature (°C)';
            app.Starttemperature = uieditfield(app.SimpleEBMITab, 'numeric');
            app.Starttemperature.Value = 20;
            app.AlbedoLabel = uilabel(app.SimpleEBMITab);
            app.AlbedoLabel.Text = 'Albedo';
            app.Albedo = uieditfield(app.SimpleEBMITab, 'numeric');
            app.Albedo.Value = 0.3;
            app.TransmissivityLabel = uilabel(app.SimpleEBMITab);
            app.TransmissivityLabel.Text = 'Transmissivity';
            app.Transmissivity = uieditfield(app.SimpleEBMITab, 'numeric');
            app.Transmissivity.Value = 0.64;
            app.SimulationtimeyearsLabel = uilabel(app.SimpleEBMITab);
            app.SimulationtimeyearsLabel.Text = 'Simulation time (years)';
            app.years_EBM = uieditfield(app.SimpleEBMITab, 'numeric');
            app.years_EBM.Value = 100;
            app.SimpleEBMIITab_2 = uitab(app.TabGroup6);
            app.SimpleEBMIITab_2.Title = 'Simple EBM II';
            app.radiativeforcing_Axes = uiaxes(app.SimpleEBMIITab_2);
            xlabel(app.radiativeforcing_Axes, 'X')
            ylabel(app.radiativeforcing_Axes, 'Y')
            app.RunmodelButton = uibutton(app.SimpleEBMIITab_2, 'push');
            app.RunmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunmodelButtonPushed, true);
            app.RunmodelButton.Text = 'Run model';
            app.surfacetempanom_Axes = uiaxes(app.SimpleEBMIITab_2);
            xlabel(app.surfacetempanom_Axes, 'X')
            ylabel(app.surfacetempanom_Axes, 'Y')
            app.TextArea_28 = uitextarea(app.SimpleEBMIITab_2);
            app.TextArea_28.Value = {'This is a 2 box global energy balance model representing a surface layer and a mid-depth ocean. This simple model was developed by Tim Osborn (a Lead Author of the IPCC AR5) for educational purposes. More info and instructions in the Ebook. Have a go at it and see in which ways you can match the observations!'};
            app.MixedlayerdepthmSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.MixedlayerdepthmSliderLabel.Text = 'Mixed layer depth (m)';
            app.MixedlayerdepthmSlider = uislider(app.SimpleEBMIITab_2);
            app.MixedlayerdepthmSlider.Limits = [40 100];
            app.MixedlayerdepthmSlider.Value = 60;
            app.ClimatesensitivityequilibriumSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.ClimatesensitivityequilibriumSliderLabel.Text = 'Climate sensitivity (equilibrium)';
            app.ClimatesensitivityequilibriumSlider = uislider(app.SimpleEBMIITab_2);
            app.ClimatesensitivityequilibriumSlider.Limits = [1.5 4.5];
            app.ClimatesensitivityequilibriumSlider.Value = 1.5;
            app.DeepoceandepthmSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.DeepoceandepthmSliderLabel.Text = 'Deep ocean depth (m)';
            app.DeepoceandepthmSlider = uislider(app.SimpleEBMIITab_2);
            app.DeepoceandepthmSlider.Limits = [100 1000];
            app.DeepoceandepthmSlider.Value = 200;
            app.NoisefactorSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.NoisefactorSliderLabel.Text = 'Noise factor';
            app.NoisefactorSlider = uislider(app.SimpleEBMIITab_2);
            app.NoisefactorSlider.Limits = [0 1];
            app.VolcanicfactorSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.VolcanicfactorSliderLabel.Text = 'Volcanic factor';
            app.VolcanicfactorSlider = uislider(app.SimpleEBMIITab_2);
            app.VolcanicfactorSlider.Limits = [0.7 1.3];
            app.VolcanicfactorSlider.Value = 0.7;
            app.SolarfactorSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.SolarfactorSliderLabel.Text = 'Solar factor';
            app.SolarfactorSlider = uislider(app.SimpleEBMIITab_2);
            app.SolarfactorSlider.Limits = [0.2 1];
            app.SolarfactorSlider.Value = 0.2;
            app.GHGfactorSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.GHGfactorSliderLabel.Text = 'GHG factor';
            app.GHGfactorSlider = uislider(app.SimpleEBMIITab_2);
            app.GHGfactorSlider.Limits = [0 1.1];
            app.GHGfactorSlider.Value = 1;
            app.SulfateaerosolsfactorSliderLabel = uilabel(app.SimpleEBMIITab_2);
            app.SulfateaerosolsfactorSliderLabel.Text = 'Sulfate aerosols factor';
            app.SulfateaerosolsfactorSlider = uislider(app.SimpleEBMIITab_2);
            app.SulfateaerosolsfactorSlider.Limits = [0.4 1.7];
            app.SulfateaerosolsfactorSlider.Value = 0.4;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CC_app_GUI_201126

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @CC_estimator_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end